classdef appColeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainApp                         matlab.ui.Figure
        Grid1                           matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        Tab1_Connection                 matlab.ui.container.Tab
        Connection_Grid0                matlab.ui.container.GridLayout
        Connection_ConnectButton        matlab.ui.control.Button
        Connection_EditButton           matlab.ui.control.StateButton
        Tree1                           matlab.ui.container.Tree
        Connection_deleteInstrument     matlab.ui.control.Image
        Connection_addInstrument        matlab.ui.control.Image
        Connection_Panel2               matlab.ui.container.Panel
        Connection_Grid2                matlab.ui.container.GridLayout
        Connection_Grid4                matlab.ui.container.GridLayout
        Instr_attFactor                 matlab.ui.control.TextArea
        AtenuaoLabel_3                  matlab.ui.control.Label
        ResoluoLabel                    matlab.ui.control.Label
        Instr_Resolution                matlab.ui.control.TextArea
        MododotraoLabel                 matlab.ui.control.Label
        Instr_TraceMode                 matlab.ui.control.TextArea
        DetectorLabel                   matlab.ui.control.Label
        Instr_Detector                  matlab.ui.control.TextArea
        Connection_Grid3                matlab.ui.container.GridLayout
        Instr_Preamp                    matlab.ui.control.CheckBox
        Instr_maxLevel                  matlab.ui.control.EditField
        PotnciamximaLabel               matlab.ui.control.Label
        Instr_Image                     matlab.ui.control.Image
        Instr_Points                    matlab.ui.control.TextArea
        PontosporvarreduraLabel_2       matlab.ui.control.Label
        FaixadefrequnciaLabel           matlab.ui.control.Label
        Instr_freqRange                 matlab.ui.control.EditField
        Instr_PreampLabel               matlab.ui.control.Label
        Instr_Description               matlab.ui.control.Label
        Connection_Label2               matlab.ui.control.Label
        Connection_Panel1               matlab.ui.container.Panel
        Connection_Grid1                matlab.ui.container.GridLayout
        EndereoIPEditField_2Label       matlab.ui.control.Label
        Connection_IP                   matlab.ui.control.EditField
        TipodeconexoDropDownLabel       matlab.ui.control.Label
        Connection_Type                 matlab.ui.control.DropDown
        Connection_Name                 matlab.ui.control.DropDown
        FabricanteemodeloDropDownLabel  matlab.ui.control.Label
        BufferentradaMBEditFieldLabel   matlab.ui.control.Label
        Connection_Buffer               matlab.ui.control.NumericEditField
        Connection_Family               matlab.ui.control.DropDown
        TipoDropDownLabel               matlab.ui.control.Label
        Connection_Port                 matlab.ui.control.EditField
        PortaEditFieldLabel             matlab.ui.control.Label
        Connection_Timeout              matlab.ui.control.NumericEditField
        TimeoutsegEditField_2Label      matlab.ui.control.Label
        Connection_Description          matlab.ui.control.EditField
        DescrioopcionalLabel            matlab.ui.control.Label
        Connection_Label1               matlab.ui.control.Label
        Tab2_Task                       matlab.ui.container.Tab
        Task_Grid0                      matlab.ui.container.GridLayout
        Task_Grid6                      matlab.ui.container.GridLayout
        Task_addTask                    matlab.ui.control.Button
        Tree3                           matlab.ui.container.Tree
        Task_deleteBand                 matlab.ui.control.Image
        Task_addBand                    matlab.ui.control.Image
        Task_Panel4                     matlab.ui.container.Panel
        Task_Grid7                      matlab.ui.container.GridLayout
        Task_BandUnit6                  matlab.ui.control.Label
        Task_BandUnit5                  matlab.ui.control.Label
        Task_BandUnit4                  matlab.ui.control.Label
        FrequnciainicialEditField_2Label  matlab.ui.control.Label
        ResoluoEditFieldLabel           matlab.ui.control.Label
        Task_BandUnit3                  matlab.ui.control.Label
        FrequnciafinalEditField_3Label  matlab.ui.control.Label
        Task_BandUnit2                  matlab.ui.control.Label
        Task_BandUnit1                  matlab.ui.control.Label
        ThreadIDEditFieldLabel          matlab.ui.control.Label
        PramplificadorDropDownLabel     matlab.ui.control.Label
        MododoatenuadorDropDownLabel    matlab.ui.control.Label
        AtenuadorDropDownLabel          matlab.ui.control.Label
        UnidadeDropDownLabel            matlab.ui.control.Label
        PassodavarreduraEditField_2Label  matlab.ui.control.Label
        PontosportraoSpinnerLabel       matlab.ui.control.Label
        MododotraoDropDownLabel         matlab.ui.control.Label
        DetectorDropDownLabel           matlab.ui.control.Label
        TempoderevisitaEditFieldLabel   matlab.ui.control.Label
        TempodeintegraoEditFieldLabel   matlab.ui.control.Label
        ThresholdLabel                  matlab.ui.control.Label
        DescrioEditFieldLabel           matlab.ui.control.Label
        Task_ThreadID                   matlab.ui.control.NumericEditField
        Task_Description                matlab.ui.control.EditField
        Task_F0                         matlab.ui.control.NumericEditField
        Task_F1                         matlab.ui.control.NumericEditField
        Task_Resolution                 matlab.ui.control.NumericEditField
        Task_StepWidth                  matlab.ui.control.NumericEditField
        Task_DataPoints                 matlab.ui.control.Spinner
        Task_TraceMode                  matlab.ui.control.DropDown
        Task_Detector                   matlab.ui.control.DropDown
        Task_LevelUnit                  matlab.ui.control.DropDown
        Task_Preamp                     matlab.ui.control.DropDown
        Task_attMode                    matlab.ui.control.DropDown
        Task_attFactor                  matlab.ui.control.DropDown
        Task_revisitTime                matlab.ui.control.NumericEditField
        Task_integrationTime            matlab.ui.control.NumericEditField
        Task_THR                        matlab.ui.control.NumericEditField
        Task_Warning                    matlab.ui.control.Image
        Task_Label9                     matlab.ui.control.Label
        Task_Grid2                      matlab.ui.container.GridLayout
        NomeEditFieldLabel              matlab.ui.control.Label
        CodificaobitsporpontoDropDownLabel  matlab.ui.control.Label
        AnalisadordeespectroreceptorDropDownLabel  matlab.ui.control.Label
        GPSDropDownLabel                matlab.ui.control.Label
        Task_Panel2                     matlab.ui.container.Panel
        Task_Grid4                      matlab.ui.container.GridLayout
        LatitudeEditFieldLabel          matlab.ui.control.Label
        Task_manualLongitude            matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        Task_manualLatitude             matlab.ui.control.NumericEditField
        Task_GPS                        matlab.ui.control.DropDown
        Task_Instrument                 matlab.ui.control.DropDown
        Task_Label8                     matlab.ui.control.Label
        Task_Panel3                     matlab.ui.container.Panel
        Task_Grid5                      matlab.ui.container.GridLayout
        AlturadeinstalaoEditFieldLabel  matlab.ui.control.Label
        AzimuteEditFieldLabel           matlab.ui.control.Label
        ElevaoEditFieldLabel            matlab.ui.control.Label
        Task_AntennaUnit3               matlab.ui.control.Label
        Task_AntennaElevation           matlab.ui.control.NumericEditField
        Task_AntennaUnit2               matlab.ui.control.Label
        Task_AntennaAzimuth             matlab.ui.control.NumericEditField
        Task_AntennaUnit1               matlab.ui.control.Label
        Task_AntennaHeight              matlab.ui.control.NumericEditField
        Task_Antenna                    matlab.ui.control.DropDown
        AntenaDropDownLabel             matlab.ui.control.Label
        Task_Panel1                     matlab.ui.container.Panel
        Task_Grid3                      matlab.ui.container.GridLayout
        Task_startImediately            matlab.ui.control.CheckBox
        DuraoEditFieldLabel             matlab.ui.control.Label
        Task_DurationUnit               matlab.ui.control.DropDown
        Task_Duration                   matlab.ui.control.NumericEditField
        Task_startTimeMinutes           matlab.ui.control.Spinner
        Task_startTimeHours             matlab.ui.control.Spinner
        IncioDatePickerLabel            matlab.ui.control.Label
        Task_startTimeDate              matlab.ui.control.DatePicker
        Task_Label7                     matlab.ui.control.Label
        Task_BitsPerPoint               matlab.ui.control.DropDown
        Task_Name                       matlab.ui.control.EditField
        Task_Label6                     matlab.ui.control.Label
        Task_Grid1                      matlab.ui.container.GridLayout
        Task_Label5                     matlab.ui.control.Label
        Task_Label4                     matlab.ui.control.Label
        Task_Label3                     matlab.ui.control.Label
        Task_Label2                     matlab.ui.control.Label
        Tree2                           matlab.ui.container.Tree
        Scriptunico2021Node             matlab.ui.container.TreeNode
        FMeTV_UHFNode                   matlab.ui.container.TreeNode
        Task_deleteTask                 matlab.ui.control.Image
        Task_Label1                     matlab.ui.control.Label
        Tab3_Spec                       matlab.ui.container.Tab
        ToolBar_Grid                    matlab.ui.container.GridLayout
        ToolBar_Button5                 matlab.ui.control.Button
        ToolBar_Button4                 matlab.ui.control.Button
        ToolBar_Button3                 matlab.ui.control.Button
        ToolBar_Button2                 matlab.ui.control.Button
        ToolBar_Button1                 matlab.ui.control.Button
        menu_Panel                      matlab.ui.container.Panel
        menu_Grid0                      matlab.ui.container.GridLayout
        menu_Grid3                      matlab.ui.container.GridLayout
        menu_searchPath                 matlab.ui.control.Image
        menu_userPath                   matlab.ui.control.DropDown
        menu_Grid1                      matlab.ui.container.GridLayout
        menu_Button9                    matlab.ui.control.StateButton
        menu_Separator2                 matlab.ui.control.Image
        menu_Separator1                 matlab.ui.control.Image
        menu_Grid2                      matlab.ui.container.GridLayout
        menu_Button8                    matlab.ui.control.StateButton
        menu_Button7                    matlab.ui.control.StateButton
        menu_Button6                    matlab.ui.control.StateButton
        menu_Button5                    matlab.ui.control.StateButton
        menu_Button4                    matlab.ui.control.Button
        menu_Button3                    matlab.ui.control.StateButton
        menu_Button2                    matlab.ui.control.StateButton
        menu_Button1                    matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        
        RootFolder = [];
        
        instrList
        taskList
        
        Instrument
        gpsInstrument
        
        % 'ROHDE&SCHWARZ,EB500,101.499/002,V04.01-4072.8710.00'
        
    end
    
    
    methods (Access = private)
        
        function Fcn_instrInfo(app, instrumentName)
            
            switch instrumentName
                case 'CRFS RFeye Node 20-6'
                    app.Instr_Image.ImageSource = '_CRFS.png';
                    app.Instr_Description.Text  = '...';
                    app.Instr_freqRange.Value   = '20 MHz - 6 GHz';
                    app.Instr_maxLevel.Value    = '30 dBm';
                    app.Instr_Points.Value      = '101 - 32001';
                    app.Instr_Preamp.Value      = 1;
                    app.Instr_attFactor.Value   = {'0 dB'; '10 dB'; '20 dB'; '30 dB'};
                    app.Instr_Resolution.Value  = {'100 kHz'; '200 kHz'; '300 kHz'; '1 MHz'; '10 MHz'};
                    app.Instr_TraceMode.Value   = {'Clear Write'; 'Max Hold'; 'Average'};
                    app.Instr_Detector.Value    = {'Fast'};

                case 'Rohde & Schwarz EB500'
                    app.Instr_Image.ImageSource = '_R&S_EB500.png';
                    app.Instr_Description.Text  = ['Adquirido entre 2005 e 2006. Alta sensibilidade '                ...
                                                   '(em especial quando habilitado o pré-amplificador de 20dB), '    ...
                                                   'rapidez (até 2.5ms) e número de pontos por traço configurável, ' ...
                                                   'podendo coincidir com canalização da faixa monitorada.'];
                    app.Instr_freqRange.Value   = '10 MHz - 6 GHz';
                    app.Instr_maxLevel.Value    = '30 dBm';
                    app.Instr_Points.Value      = '101 - 32001';
                    app.Instr_Preamp.Value      = 0;
                    app.Instr_attFactor.Value   = {'0 dB'; '10 dB'; '20 dB'; '30 dB'};
                    app.Instr_Resolution.Value  = {'100 kHz'; '200 kHz'; '300 kHz'; '1 MHz'; '10 MHz'};
                    app.Instr_TraceMode.Value   = {'Clear Write'; 'Max Hold'; 'Min Hold'; 'Average'};
                    app.Instr_Detector.Value    = {'Fast'; 'RMS'; 'Positive'};
                
                case 'Rohde & Schwarz FSL-6'
                    app.Instr_Image.ImageSource = '_R&S_FSL6.png';
                    app.Instr_Description.Text  = ['Adquirido entre 2005 e 2006. Alta sensibilidade '                ...
                                                   '(em especial quando habilitado o pré-amplificador de 20dB), '    ...
                                                   'rapidez (até 2.5ms) e número de pontos por traço configurável, ' ...
                                                   'podendo coincidir com canalização da faixa monitorada.'];
                    app.Instr_freqRange.Value   = '10 MHz - 6 GHz';
                    app.Instr_maxLevel.Value    = '30 dBm';
                    app.Instr_Preamp.Value      = 1;
                    app.Instr_attFactor.Value   = {'0 dB'; '5 dB'; '10 dB'; '15 dB'; '20 dB'; '25 dB'; '30 dB'};
                    app.Instr_Resolution.Value  = {'1 kHz'; '3 kHz'; '10 kHz'; '30 kHz'; '100 kHz'; '200 kHz'; '300 kHz'; '1 MHz'};
                    app.Instr_TraceMode.Value   = {'Average'; 'Clear Write'; 'Max Hold'; 'Min Hold'};
                    app.Instr_Detector.Value    = {'Auto Peak'; 'Average'; 'Negative Peak'; 'Positive Peak'; 'Quasi Peak'; 'RMS'; 'Sample'};
                
                case 'Rohde & Schwarz FSVR-20'
                    app.Instr_Image.ImageSource = '_R&S_FSVR.png';
                    app.Instr_Description.Text  = ['Adquirido entre 2005 e 2006. Alta sensibilidade '                ...
                                                   '(em especial quando habilitado o pré-amplificador de 20dB), '    ...
                                                   'rapidez (até 2.5ms) e número de pontos por traço configurável, ' ...
                                                   'podendo coincidir com canalização da faixa monitorada.'];
                    app.Instr_freqRange.Value   = '10 MHz - 6 GHz';
                    app.Instr_maxLevel.Value    = '30 dBm';
                    app.Instr_Preamp.Value      = 0;
                    app.Instr_attFactor.Value   = {'0 dB'; '5 dB'; '10 dB'; '15 dB'; '20 dB'; '25 dB'; '30 dB'};
                    app.Instr_Resolution.Value  = {'1 kHz'; '3 kHz'; '10 kHz'; '30 kHz'; '100 kHz'; '200 kHz'; '300 kHz'; '1 MHz'};
                    app.Instr_TraceMode.Value   = {'Average'; 'Clear Write'; 'Max Hold'; 'Min Hold'};
                    app.Instr_Detector.Value    = {'Auto Peak'; 'Average'; 'Negative Peak'; 'Positive Peak'; 'Quasi Peak'; 'RMS'; 'Sample'};
            end
            
        end
        
        
        function Fcn_Tree1(app)
            
            delete(app.Tree1.Children);
            
            app.Connection_Family.Enable = 1;
            app.Tree1.Enable             = 1;
            
            app.Connection_deleteInstrument.Enable = 0;
            app.Connection_ConnectButton.Enable    = 0;
            set(app.Connection_EditButton, 'Enable', 0, 'Value', 0);
            
            value = length(app.instrList);
            if value
                for ii = 1:value
                    if contains(app.instrList(ii).Name, 'RFeye', 'IgnoreCase', true)
                        imageIcon = 'CRFS.png';
                    elseif contains(app.instrList(ii).Name, {'FSL-6', 'FSVR-20', 'EB500'}, 'IgnoreCase', true)
                        imageIcon = 'R&S Icon.png';
                    elseif contains(app.instrList(ii).Name, {'GPS', 'Smartphone'}, 'IgnoreCase', true)
                        imageIcon = 'global-icon-13.png';
                    end
                    
                    if ~isempty(app.instrList(ii).Description)
                        tempNode = uitreenode(app.Tree1, 'Text', sprintf('%d: %s (%s)', ii, app.instrList(ii).Name, app.instrList(ii).Description), 'NodeData', ii, 'Icon', imageIcon);
                    else
                        tempNode = uitreenode(app.Tree1, 'Text', sprintf('%d: %s', ii, app.instrList(ii).Name), 'NodeData', ii, 'Icon', imageIcon);
                    end
                    uitreenode(tempNode, 'Text', ['Tipo de conexão: ' app.instrList(ii).Type], 'NodeData', ii);
                    
                    if ~isempty(app.instrList(ii).IP)
                        uitreenode(tempNode, 'Text', ['Endereço IP: ' app.instrList(ii).IP],   'NodeData', ii);
                    end
                    
                    uitreenode(tempNode, 'Text', sprintf('Porta: %s', app.instrList(ii).Port),                   'NodeData', ii);
                    uitreenode(tempNode, 'Text', sprintf('Timeout : %.0f seg', app.instrList(ii).Timeout),       'NodeData', ii);
                    uitreenode(tempNode, 'Text', sprintf('Buffer entrada: %.0f MB', app.instrList(ii).inBuffer), 'NodeData', ii);
                    
                end
            end
            
        end
        
        
        function Fcn_Tree2(app)
            
            delete(app.Tree2.Children);
            
            value = length(app.taskList.Task);
            if value
                for ii = 1:value
                    uitreenode(app.Tree2, 'Text', app.taskList.Task{ii}.Name, 'NodeData', ii);
                end
            end
            app.Task_Label3.Text    = app.taskList.Task{app.taskList.SelectedTask}.Name;
            app.Tree2.SelectedNodes = app.Tree2.Children(1);
            Tree2SelectionChanged(app)
            
        end
        
        
        function Fcn_Tree3(app, ind)
            
            delete(app.Tree3.Children);
            
            value = length(app.taskList.Task{ind}.Band);
            if value
                for ii = 1:value
                    str = sprintf('ThreadID %.0f: %.3f - %.3f MHz (%.3f kHz, %.0f pontos por traço)', ...
                                   app.taskList.Task{ind}.Band(ii).ThreadID,          ...
                                   app.taskList.Task{ind}.Band(ii).F0 / 1e+6,         ...
                                   app.taskList.Task{ind}.Band(ii).F1 / 1e+6,         ...
                                   app.taskList.Task{ind}.Band(ii).Resolution / 1e+3, ...
                                   app.taskList.Task{ind}.Band(ii).DataPoints);

                    uitreenode(app.Tree3, 'Text', str, 'NodeData', ii);
                end
            end
            app.Tree3.SelectedNodes = app.Tree3.Children(1);
            
        end
        
        
        function Fcn_MetaData(app, ind1, ind2)
        
            app.Task_Name.Value             = app.taskList.Task{ind1}.Name;            
            app.Task_BitsPerPoint.Value     = app.taskList.Task{ind1}.BitsPerPoint;
            
            app.Task_startTimeDate.Value    = app.taskList.Task{ind1}.startTime;
            app.Task_startTimeHours.Value   = hour(app.taskList.Task{ind1}.startTime);
            app.Task_startTimeMinutes.Value = minute(app.taskList.Task{ind1}.startTime);
            app.Task_startImediately.Value  = 0;
            
            switch app.taskList.Task{ind1}.Antenna
                case 1; app.Task_Antenna.Value = 'CRFS Low Band (10 MHz - 1.2 GHz)';
                case 2; app.Task_Antenna.Value = 'CRFS High Band (750 MHz - 6 GHz)';
                case 3; app.Task_Antenna.Value = 'Rohde & Schwarz ADD107';
                case 4; app.Task_Antenna.Value = 'Rohde & Schwarz ADD207';
                otherwise
                    app.Task_Antenna.Value = 'Telescópica';
            end
            app.Task_AntennaHeight.Value    = app.taskList.Task{ind1}.AntennaHeight;
            app.Task_AntennaAzimuth.Value   = app.taskList.Task{ind1}.AntennaAzimuth;
            app.Task_AntennaElevation.Value = app.taskList.Task{ind1}.AntennaElevation;


            app.Task_ThreadID.Value    = app.taskList.Task{ind1}.Band(ind2).ThreadID;
            app.Task_Description.Value = app.taskList.Task{ind1}.Band(ind2).Description;
            
            app.Task_F0.Value         = app.taskList.Task{ind1}.Band(ind2).F0 / 1e+6;
            app.Task_F1.Value         = app.taskList.Task{ind1}.Band(ind2).F1 / 1e+6;
            app.Task_Resolution.Value = app.taskList.Task{ind1}.Band(ind2).Resolution / 1e+3;
            
            app.Task_DataPoints.Value = app.taskList.Task{ind1}.Band(ind2).DataPoints;
            app.Task_StepWidth.Value  = (app.taskList.Task{ind1}.Band(ind2).F1 - app.taskList.Task{ind1}.Band(ind2).F0) / ((app.taskList.Task{ind1}.Band(ind2).DataPoints - 1)*1000);
            app.Task_TraceMode.Value  = app.taskList.Task{ind1}.Band(ind2).TraceMode;
            app.Task_Detector.Value   = app.taskList.Task{ind1}.Band(ind2).Detector;
            app.Task_LevelUnit.Value  = app.taskList.Task{ind1}.Band(ind2).LevelUnit;
            
            if app.taskList.Task{ind1}.Band(ind2).Preamp; app.Task_Preamp.Value = 'On';
            else;                                         app.Task_Preamp.Value = 'Off';
            end
            
            if app.taskList.Task{ind1}.Band(ind2).attMode
                app.Task_attMode.Value = 'Automático';
                
                set(app.AtenuadorDropDownLabel, 'Visible', 0);
                set(app.Task_attFactor,         'Visible', 0);
            
            else
                app.Task_attMode.Value = 'Manual';
                
                set(app.AtenuadorDropDownLabel, 'Visible', 1);
                set(app.Task_attFactor,         'Visible', 1, ...
                                                'Value', sprintf('%.0f dB', app.taskList.Task{ind1}.Band(ind2).attFactor));
            end
            
            app.Task_revisitTime.Value = app.taskList.Task{ind1}.Band(ind2).revisitTime;
            
            if app.taskList.Task{ind1}.Band(ind2).integrationTime
                app.TempodeintegraoEditFieldLabel.Visible = 1;
                app.Task_BandUnit6.Visible                = 1;
                set(app.Task_integrationTime, 'Visible', 1, 'Value', app.taskList.Task{ind1}.Band(ind2).integrationTime);
            else
                app.TempodeintegraoEditFieldLabel.Visible = 0;
                app.Task_BandUnit6.Visible                = 0;
                set(app.Task_integrationTime, 'Visible', 0)
            end
            
            if isempty(app.taskList.Task{ind1}.Band(ind2).Threshold)
                app.ThresholdLabel.Visible = 0;
                set(app.Task_THR, 'Visible', 0);
            else
                app.ThresholdLabel.Visible = 1;
                set(app.Task_THR, 'Visible', 1, 'Value', app.taskList.Task{ind1}.Band(ind2).Threshold);
            end
            
        end
        
        
        function gps = Fcn_gpsConnection(app, ind)
            
            Type     = app.instrList(ind).Type;
            IP       = app.instrList(ind).IP;
            Port     = app.instrList(ind).Port;
            Timeout  = app.instrList(ind).Timeout;
            inBuffer = app.instrList(ind).inBuffer;
            
            switch Type
                case 'Serial'
                    app.gpsInstrument = instrfind('Type', 'serial', 'Port', Port, 'Tag', '');
                    if isempty(app.gpsInstrument)
                        app.gpsInstrument = serial(Port);
                    else
                        fclose(app.gpsInstrument);
                        app.gpsInstrument = app.gpsInstrument(1);
                    end
                
                case 'TCPIP Socket'
                    app.gpsInstrument = instrfind('Type', 'tcpip', 'RemoteHost', IP, 'RemotePort', Port, 'Tag', '');
                    if isempty(app.gpsInstrument)
                        app.gpsInstrument = tcpip(IP, Port);
                    else
                        fclose(app.gpsInstrument);
                        app.gpsInstrument = app.gpsInstrument(1);
                    end   
            end
            set(app.gpsInstrument, 'Timeout', Timeout, 'InputBufferSize', inBuffer*1e+6)
            fopen(app.gpsInstrument);
            
            gps = Fcn_gpsReader1(app, Timeout);
            
        end
        
        
        function gps = Fcn_gpsReader1(app, Timeout)
            
            gps = struct('Status',     [], ...
                         'Latitude',   [], ...
                         'Longitude',  [], ...
                         'TimeStamp',  []);
            
            gpsTic = tic;
            t = toc(gpsTic);
            while t < Timeout
                receivedData = fscanf(app.gpsInstrument, '%c\n');
                
                if ~isempty(receivedData) & strcmp(receivedData(4:6), 'RMC')
                    checksum = Fcn_CheckSumValidation(app, receivedData);
                    if ~strcmpi(receivedData(end-1:end), checksum)
                        warning('CheckSum com erro!')
                        continue
                    end
                    receivedData = strsplit(receivedData, ',');
                    
                    if strcmp(receivedData{3}, 'A'); gps.Status = 1;
                    else                           ; gps.Status = 0;
                    end
                    
                    gps.Latitude = str2double(receivedData{4}(1:2)) + ...
                                   str2double(receivedData{4}(3:end)) ./ 60;
                    if strcmp(receivedData{5}, 'S')
                        gps.Latitude = -gps.Latitude;
                    end
                    
                    gps.Longitude = str2double(receivedData{6}(1:3)) + ...
                                    str2double(receivedData{6}(4:end)) ./ 60;
                    if strcmp(receivedData{7}, 'W')
                        gps.Longitude = -gps.Longitude;
                    end
                    
                    if contains(receivedData{2}, '.')
                        dataFormat = ['HHmmss.' repmat('S', 1, numel(extractAfter(receivedData{2}, '.'))) ' ddMMyy'];
                    else
                        dataFormat = 'HHmmss ddMMyy';
                    end
                    gps.TimeStamp = datetime([receivedData{2} ' ' receivedData{10}], 'InputFormat', dataFormat, 'Format', 'dd/MM/yyyy HH:mm:ss');
                    break  
                end
                t = toc(gpsTic);
            end
            
            if isempty(gps.Status)
                gps = [];
            end
        
        end
        
        
        function checksum = Fcn_CheckSumValidation(app, nmeaData)
        
            nmeaData = replace(char(extractBetween(nmeaData, '$', '*')), ',', '');
            
            checksum = uint8(0);
            for ii = 1:numel(nmeaData)
                checksum = bitxor(checksum, uint8(nmeaData(ii)));
            end
            
            checksum = dec2hex(checksum);
            if numel(checksum) == 1
                checksum = ['0' checksum];
            end
        
        end
        
        
        function Node = Fcn_instrConnection(app, ind)
            
            Type     = app.instrList(ind).Type;
            IP       = app.instrList(ind).IP;
            Port     = str2double(app.instrList(ind).Port);
            Timeout  = app.instrList(ind).Timeout;
            inBuffer = app.instrList(ind).inBuffer;
        
            switch Type
                case 'Visa TCPIP'
                    app.Instrument = instrfind('Type', 'visa-tcpip', 'RsrcName', ['TCPIP0::' IP '::inst0::INSTR'], 'Tag', '');
                    if isempty(app.Instrument)
                        app.Instrument = visa('ni', ['TCPIP0::' IP '::inst0::INSTR']);
                    else
                        fclose(app.Instrument);
                        app.Instrument = app.Instrument(1);
                    end
                    
                case 'TCPIP Socket'
                    app.Instrument = instrfind('Type', 'tcpip', 'RemoteHost', IP, 'RemotePort', Port, 'Tag', '');
                    if isempty(app.Instrument)
                        app.Instrument = tcpip(IP, Port);
                    else
                        fclose(app.Instrument);
                        app.Instrument = app.Instrument(1);
                    end
            end 
            set(app.Instrument, 'Timeout', Timeout, 'InputBufferSize', inBuffer*1e+6, 'ByteOrder', 'littleEndian');
            fopen(app.Instrument);
            
            Node = deblank(query(app.Instrument, '*IDN?'));
            
        end
        
        
        function Fcn_uiAlert(app, msg, title, type)
            
            uialert(app.MainApp, msg, title, 'Icon', type)
            
        end
        
        
        function Fcn_ModeOperation(app)
            
            app.menu_Button5.Value = 0;
            app.menu_Button6.Value = 0;
            app.menu_Button9.Value = 0;
    
            if app.menu_Button1.Value
                ind = 1;
                set(app.menu_Grid2.Children, 'Enable', 0)
                app.menu_Button9.Enable = 0;
                
            elseif app.menu_Button2.Value
                ind = 2;
                set(app.menu_Grid2.Children, 'Enable', 1)
                app.menu_Button9.Enable = 0;
                
                Fcn_ModeOperation_Logger(app)
                
            elseif app.menu_Button3.Value
                ind = 3;
                set(app.menu_Grid2.Children, 'Enable', 0)
                app.menu_Button9.Enable = 1;
            end
            app.TabGroup.SelectedTab = app.TabGroup.Children(ind);
            
        end
        
        
        function Fcn_ModeOperation_Logger(app)
            
            value = length(app.instrList);
            
            str1 = {};
            str2 = {'0: Manual'};
            
            ind  = 1;
            
            if value
                for ii = 1:value
                    if contains(app.instrList(ii).Name, {'FSL-6', 'FSVR-20'}, 'IgnoreCase', true)
                        str1 = [str1, sprintf('%d: %s', ii, app.instrList(ii).Name)]; 
                    
                    elseif contains(app.instrList(ii).Name, 'EB500', 'IgnoreCase', true)
                        str1 = [str1, sprintf('%d: %s', ii, app.instrList(ii).Name)];
                        
                        if ii == 1
                            ind  = ii+1;
                            str2 = [str2, sprintf('%d: %s', ii, app.instrList(ii).Name)];
                        end
                        
                    elseif contains(app.instrList(ii).Name, {'GPS', 'Smartphone'}, 'IgnoreCase', true)
                        str2 = [str2, sprintf('%d: %s', ii, app.instrList(ii).Name)];
                    end
                end
            end
            
            if ~isempty(str1)
                app.Task_Instrument.Items = str1;
            end
            
            if ~isempty(str2)
                app.Task_GPS.Items = str2;
                app.Task_GPS.Value = app.Task_GPS.Items{ind};
                
                Task_GPSValueChanged(app)
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            [app.instrList, app.taskList] = Fcn_startup();
            
            Fcn_Tree1(app)
            Fcn_Tree2(app)
            
            app.Task_Warning.Visible = 0;
            
        end

        % Value changed function: Connection_Family
        function Connection_FamilyValueChanged(app, event)
            
            switch app.Connection_Family.Value
                case 'Analisador de espectro / receptor'
                    set(app.Connection_Name, 'Items', {'CRFS RFeye Node 20-6',     ...
                                                       'Rohde & Schwarz EB500',    ...
                                                       'Rohde & Schwarz FSL-6',    ...
                                                       'Rohde & Schwarz FSVR-20'}, ...
                                             'Value',  'Rohde & Schwarz FSL-6');
                    
                    set(app.Connection_Type, 'Items', {'TCPIP Socket', 'Visa TCPIP'}, ...
                                             'Value', 'TCPIP Socket');
                    
                    app.Connection_IP.Enable  = 1;
                    app.Connection_Port.Value = '5025';
                    
                case 'GPS'
                    set(app.Connection_Name, 'Items', {'Garmin GPSmap 62sc', ...
                                                       'Smartphone'},        ...
                                             'Value',  'Garmin GPSmap 62sc');
                    
                    set(app.Connection_Type, 'Items', {'Serial', 'TCPIP Socket'}, ...
                                             'Value',  'Serial');
                    
                    app.Connection_IP.Enable  = 0;
                    app.Connection_Port.Value = 'COM1';
                    
            end
            
        end

        % Value changed function: Connection_Name
        function Connection_NameValueChanged(app, event)
            
            switch app.Connection_Name.Value
                case {'Rohde & Schwarz FSL-6', 'Rohde & Schwarz FSVR'}
                    set(app.Connection_Type, 'Items', {'TCPIP Socket', 'Visa TCPIP'}, ...
                                             'Value', 'TCPIP Socket');
                    
                    app.Connection_IP.Enable  = 1;
                    app.Connection_Port.Value = '5025';
                    
                case 'Rohde & Schwarz EB500'
                    set(app.Connection_Type, 'Items', {'TCPIP Socket', 'UDPIP Socket'}, ...
                                             'Value', 'UDPIP Socket');
                    
                    app.Connection_IP.Enable  = 1;
                    app.Connection_Port.Value = '24001';
                    
                case 'CRFS RFeye Node 20-6'
                    set(app.Connection_Type, 'Items', {'UDPIP Socket'}, ...
                                             'Value', 'UDPIP Socket');
                    
                    app.Connection_IP.Enable  = 1;
                    app.Connection_Port.Value = '7891';
                    
                case 'Garmin GPSmap 62sc'
                    set(app.Connection_Type, 'Items', {'Serial', 'TCPIP Socket'}, ...
                                             'Value', 'Serial');
                    
                    app.Connection_IP.Enable  = 0;
                    app.Connection_Port.Value = 'COM1';
                    
                case 'Smartphone'
                    set(app.Connection_Type, 'Items', {'TCPIP Socket'}, ...
                                             'Value', 'TCPIP Socket');
                    
                    app.Connection_IP.Enable  = 1;
                    app.Connection_Port.Value = '1100';
                    
            end
            Fcn_instrInfo(app, app.Connection_Name.Value)
            
        end

        % Value changed function: Connection_Type
        function Connection_TypeValueChanged(app, event)
            
            switch app.Connection_Type.Value
                case 'TCPIP Socket'
                    app.Connection_IP.Enable   = 1;
                    app.Connection_Port.Enable = 1;
                    
                    switch app.Connection_Name.Value
                        case 'Rohde & Schwarz EB500'
                            app.Connection_Port.Value = '5555';
                        
                        case {'Garmin GPSmap 62sc', 'Smartphone'}
                            app.Connection_Port.Value = '1100';
                            
                        otherwise
                            app.Connection_Port.Value = '5025';
                    end
                    
                case 'UDPIP Socket'
                    app.Connection_IP.Enable    = 1;
                    app.Connection_Port. Enable = 1;
                    
                    switch app.Connection_Name.Value
                        case 'CRFS RFeye Node 20-6'
                            app.Connection_Port.Value = '7891';             % Porta indicada no script Logger

                        case 'Rohde & Schwarz EB500'
                            app.Connection_Port.Value = '24001';
                    end
                    
                case 'Visa TCPIP'
                    app.Connection_IP.Enable   = 1;
                    app.Connection_Port.Enable = 0;
                    
                case 'Serial'
                    app.Connection_IP.Enable   = 0;
                    set(app.Connection_Port, 'Enable', 1, ...
                                             'Value', 'COM1');
                    
            end
            
        end

        % Value changed function: Task_startImediately
        function Task_startImediatelyValueChanged(app, event)
            
            if app.Task_startImediately.Value
                app.Task_startTimeDate.Enable    = 0;
                app.Task_startTimeHours.Enable   = 0;
                app.Task_startTimeMinutes.Enable = 0;
            else
                app.Task_startTimeDate.Enable    = 1;
                app.Task_startTimeHours.Enable   = 1;
                app.Task_startTimeMinutes.Enable = 1;                
            end
            
        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)
            
            ind = app.Tree2.SelectedNodes.NodeData;
            app.taskList.SelectedTask = ind;
            
            Fcn_MetaData(app, ind, 1)
            Fcn_Tree3(app, ind)
            
        end

        % Selection changed function: Tree3
        function Tree3SelectionChanged(app, event)
            
            ind1 = app.Tree2.SelectedNodes.NodeData;
            ind2 = app.Tree3.SelectedNodes.NodeData;
            
            Fcn_MetaData(app, ind1, ind2)
            
        end

        % Value changed function: Task_GPS
        function Task_GPSValueChanged(app, event)
            
            if strcmp(app.Task_GPS.Value, '0: Manual')
                set(app.Task_Grid4.Children, 'Enable', 1)
            else
                set(app.Task_Grid4.Children, 'Enable', 0)
            end
            
        end

        % Value changed function: Task_DataPoints
        function Task_DataPointsValueChanged(app, event)
            
            span = (app.Task_F1.Value - app.Task_F0.Value)*1e+6;
            
            app.Task_StepWidth.Value = (span/(app.Task_DataPoints.Value-1)) / 1000;
            
        end

        % Value changed function: Task_StepWidth
        function Task_StepWidthValueChanged(app, event)

            span = (app.Task_F1.Value - app.Task_F0.Value)*1e+6;
            
            DataPoints = round(span/(1000*app.Task_StepWidth.Value) + 1);
            if DataPoints < app.Task_DataPoints.Limits(1)
                app.Task_DataPoints.Value = app.Task_DataPoints.Limits(1);
            elseif DataPoints > app.Task_DataPoints.Limits(2)
                app.Task_DataPoints.Value = app.Task_DataPoints.Limits(1);
            else
                app.Task_DataPoints.Value = DataPoints;
            end
            
            app.Task_StepWidth.Value = (span/(app.Task_DataPoints.Value-1)) / 1000;
            
        end

        % Value changed function: Task_F0, Task_F1
        function FrequnciainicialEditField_2ValueChanged(app, event)
            
            span = (app.Task_F1.Value - app.Task_F0.Value)*1e+6;
            
            if span <= 0
                app.Task_Warning.Visible   = 1;
                app.Task_StepWidth.Enable  = 0;
                app.Task_DataPoints.Enable = 0;
            else
                app.Task_Warning.Visible   = 0;
                app.Task_StepWidth.Enable  = 1;
                app.Task_DataPoints.Enable = 1;
                
                app.Task_StepWidth.Value   = (span/(app.Task_DataPoints.Value-1)) / 1000; % em kHz
            end
            
        end

        % Value changed function: Task_attMode
        function Task_attModeValueChanged(app, event)

            if strcmp(app.Task_attMode.Value, 'Automático')
                app.AtenuadorDropDownLabel.Visible = 0;
                app.Task_attFactor.Visible         = 0;
            else
                app.AtenuadorDropDownLabel.Visible = 1;
                app.Task_attFactor.Visible         = 1;
            end
            
        end

        % Image clicked function: Connection_addInstrument
        function Connection_addInstrumentClicked(app, event)
            
            if app.Connection_EditButton.Value
                ind = app.Tree1.SelectedNodes.NodeData;
                Connection_FamilyValueChanged(app)
            else
                ind = length(app.instrList)+1;
            end
            
            app.instrList(ind).Name        = app.Connection_Name.Value;
            app.instrList(ind).Type        = app.Connection_Type.Value;
            app.instrList(ind).IP          = app.Connection_IP.Value;
            app.instrList(ind).Port        = app.Connection_Port.Value;
            app.instrList(ind).Timeout     = app.Connection_Timeout.Value;
            app.instrList(ind).inBuffer    = app.Connection_Buffer.Value;
            app.instrList(ind).Description = app.Connection_Description.Value;
            
            Fcn_Tree1(app)
            
        end

        % Selection changed function: Tree1
        function Tree1SelectionChanged(app, event)
            
            app.Connection_deleteInstrument.Enable = 1;
            
            if length(app.Tree1.SelectedNodes) == 1
                app.Connection_EditButton.Enable    = 1;
                app.Connection_ConnectButton.Enable = 1;
            else
                app.Connection_EditButton.Enable    = 0;
                app.Connection_ConnectButton.Enable = 0;
            end
            
        end

        % Image clicked function: Connection_deleteInstrument
        function Connection_deleteInstrumentClicked(app, event)
            
            ind = [];
            for ii = 1:length(app.Tree1.SelectedNodes)
                ind = [ind, app.Tree1.SelectedNodes(ii).NodeData];
            end
            
            app.instrList(ind) = [];
            Fcn_Tree1(app)
            
        end

        % Button pushed function: Connection_ConnectButton
        function Connection_ConnectButtonPushed(app, event)
            
            uiprogressdlg(app.MainApp, "Indeterminate", "on");
            
            ind = app.Tree1.SelectedNodes.NodeData;
            if contains(app.instrList(ind).Name, 'RFeye', 'IgnoreCase', true)
            
                
            elseif contains(app.instrList(ind).Name, {'FSL-6', 'FSVR-20', 'EB500'}, 'IgnoreCase', true)
                try
                    Node = Fcn_instrConnection(app, ind);
                catch ME
                    str = sprintf('Identificação do erro:\n• %s\n• %s', ME.identifier, ME.message);
                    Fcn_uiAlert(app, str, app.instrList(ind).Name, 'error')
                    return
                end
                                
                if ~isempty(Node)
                    Fcn_uiAlert(app, Node, app.instrList(ind).Name, 'success')
                else
                    str = 'Apesar de aberta conexão com um dispositivo, não foi recebida resposta ao comando "*IDN?".';
                    Fcn_uiAlert(app, str, app.instrList(ind).Name, 'error')
                end
            
                
            elseif contains(app.instrList(ind).Name, {'GPS', 'Smartphone'}, 'IgnoreCase', true)
                try
                    gps = Fcn_gpsConnection(app, ind);
                catch ME
                    str = sprintf('Identificação do erro:\n• %s\n• %s', ME.identifier, ME.message);
                    Fcn_uiAlert(app, str, app.instrList(ind).Name, 'error')
                    return
                end
                                
                if ~isempty(gps)
                    City = Fcn_FindCity(gps);
                    str  = sprintf('Status: %.0f\nLatitude: %.6f\nLongitude: %.6f\nHorário: %s (UTC)\n\n%s', ...
                                   gps.Status, gps.Latitude, gps.Longitude, datestr(gps.TimeStamp, 'dd/mm/yyyy HH:MM:SS'), City);
                    Fcn_uiAlert(app, str, app.instrList(ind).Name, 'success')
                else
                    str = 'Apesar de aberta conexão com um dispositivo, não foi recebida informação NMEA/RMC.';
                    Fcn_uiAlert(app, str, app.instrList(ind).Name, 'error')
                end
            end
            
        end

        % Value changed function: Connection_EditButton
        function Connection_EditButtonPushed(app, event)
            
            if app.Connection_EditButton.Value
                ind = app.Tree1.SelectedNodes.NodeData;
                
                app.Connection_Family.Enable = 0;
                app.Tree1.Enable = 0;
                
                app.Connection_Name.Items = {app.instrList(ind).Name};
                Connection_NameValueChanged(app)
                
                app.Connection_Type.Value = app.instrList(ind).Type;
                
                if ~isempty(app.instrList(ind).IP)
                    app.Connection_IP.Value = app.instrList(ind).IP;
                end
                
                app.Connection_Port.Value        = app.instrList(ind).Port;
                app.Connection_Timeout.Value     = app.instrList(ind).Timeout;
                app.Connection_Buffer.Value      = app.instrList(ind).inBuffer;
                app.Connection_Description.Value = app.instrList(ind).Description;
            
            else
                app.Connection_Family.Enable = 1;
                app.Tree1.Enable = 1;
                
                Connection_FamilyValueChanged(app)
            end
            
        end

        % Value changed function: menu_Button5, menu_Button6
        function Button_9ValueChanged(app, event)

            if app.menu_Button5.Value
                app.menu_Button6.Value = 0;
                set(app.Task_addTask, 'Text', 'Editar tarefa', 'Enable', 1)
            elseif app.menu_Button6.Value
                app.menu_Button5.Value = 0;
                set(app.Task_addTask, 'Text', 'Incluir tarefa', 'Enable', 1)
            else
                app.Task_addTask.Enable = 0;
            end            
            
        end

        % Value changed function: menu_Button1
        function menu_Button1ValueChanged(app, event)

            if app.menu_Button1.Value
                app.menu_Button2.Value  = 0;
                app.menu_Button3.Value = 0;
            else
                app.menu_Button1.Value = 1;
            end
            Fcn_ModeOperation(app)
            
        end

        % Value changed function: menu_Button2
        function menu_Button2ValueChanged(app, event)

            if app.menu_Button2.Value
                app.menu_Button1.Value = 0;
                app.menu_Button3.Value = 0;
            else
                app.menu_Button2.Value = 1;
            end
            Fcn_ModeOperation(app)
            
        end

        % Value changed function: menu_Button3
        function menu_Button3ValueChanged(app, event)

            if app.menu_Button3.Value
                app.menu_Button1.Value = 0;
                app.menu_Button2.Value = 0;
            else
                app.menu_Button3.Value = 1;
            end
            Fcn_ModeOperation(app)
            
        end

        % Value changed function: Task_Instrument
        function Task_InstrumentValueChanged(app, event)
            
            value = length(app.instrList);
            
            str2 = {'0: Manual'};
            ind  = 1;
    
            if contains(app.Task_Instrument.Value, 'EB500', 'IgnoreCase', true)
                for ii = 1:value
                    if strcmp(sprintf('%d: %s', ii, app.instrList(ii).Name), app.Task_Instrument.Value)
                        ind  = ii;
                        str2 = [str2, sprintf('%d: %s', ii, app.instrList(ii).Name)];
                        
                    elseif contains(app.instrList(ii).Name, {'GPS', 'Smartphone'}, 'IgnoreCase', true)
                        str2 = [str2, sprintf('%d: %s', ii, app.instrList(ii).Name)];
                    end
                end
                ind = find(strcmp(str2, app.Task_Instrument.Value));
                
            else
                for ii = 1:value
                    if contains(app.instrList(ii).Name, {'GPS', 'Smartphone'}, 'IgnoreCase', true)
                        str2 = [str2, sprintf('%d: %s', ii, app.instrList(ii).Name)];
                    end
                end
                
            end
            app.Task_GPS.Items = str2;
            app.Task_GPS.Value = app.Task_GPS.Items{ind};
            
            Task_GPSValueChanged(app)
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            if app.TabGroup.SelectedTab == app.TabGroup.Children(1)
                app.menu_Button1.Value = 1;
                menu_Button1ValueChanged(app)
                
            elseif app.TabGroup.SelectedTab == app.TabGroup.Children(2)
                app.menu_Button2.Value = 1;
                menu_Button2ValueChanged(app)
                
            elseif app.TabGroup.SelectedTab == app.TabGroup.Children(3)
                app.menu_Button3.Value = 1;
                menu_Button3ValueChanged(app)
            end            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainApp = uifigure('Visible', 'off');
            app.MainApp.Name = 'appColeta';
            app.MainApp.Icon = 'Bluemine.png';
            app.Grid1 = uigridlayout(app.MainApp);
            app.TabGroup = uitabgroup(app.Grid1);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab1_Connection = uitab(app.TabGroup);
            app.Tab1_Connection.Title = 'α';
            app.Connection_Grid0 = uigridlayout(app.Tab1_Connection);
            app.Connection_ConnectButton = uibutton(app.Connection_Grid0, 'push');
            app.Connection_ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @Connection_ConnectButtonPushed, true);
            app.Connection_ConnectButton.Icon = 'Connect.png';
            app.Connection_ConnectButton.Enable = 'off';
            app.Connection_ConnectButton.Text = 'Conectividade';
            app.Connection_EditButton = uibutton(app.Connection_Grid0, 'state');
            app.Connection_EditButton.ValueChangedFcn = createCallbackFcn(app, @Connection_EditButtonPushed, true);
            app.Connection_EditButton.Icon = 'Edit2.png';
            app.Connection_EditButton.Text = '';
            app.Tree1 = uitree(app.Connection_Grid0);
            app.Tree1.Multiselect = 'on';
            app.Tree1.SelectionChangedFcn = createCallbackFcn(app, @Tree1SelectionChanged, true);
            app.Connection_deleteInstrument = uiimage(app.Connection_Grid0);
            app.Connection_deleteInstrument.ImageClickedFcn = createCallbackFcn(app, @Connection_deleteInstrumentClicked, true);
            app.Connection_deleteInstrument.Enable = 'off';
            app.Connection_deleteInstrument.ImageSource = 'adicionar3d.png';
            app.Connection_addInstrument = uiimage(app.Connection_Grid0);
            app.Connection_addInstrument.ImageClickedFcn = createCallbackFcn(app, @Connection_addInstrumentClicked, true);
            app.Connection_addInstrument.ImageSource = 'adicionar3a.png';
            app.Connection_Panel2 = uipanel(app.Connection_Grid0);
            app.Connection_Grid2 = uigridlayout(app.Connection_Panel2);
            app.Connection_Grid4 = uigridlayout(app.Connection_Grid2);
            app.Instr_attFactor = uitextarea(app.Connection_Grid4);
            app.Instr_attFactor.Editable = 'off';
            app.Instr_attFactor.Value = {'0 dB'; '5 dB'; '10 dB'; '15 dB'; '20 dB'; '25 dB'; '30 dB'};
            app.AtenuaoLabel_3 = uilabel(app.Connection_Grid4);
            app.AtenuaoLabel_3.Text = 'Atenuação:';
            app.ResoluoLabel = uilabel(app.Connection_Grid4);
            app.ResoluoLabel.Text = 'Resolução:';
            app.Instr_Resolution = uitextarea(app.Connection_Grid4);
            app.Instr_Resolution.Editable = 'off';
            app.Instr_Resolution.Value = {'1 kHz'; '3 kHz'; '10 kHz'; '30 kHz'; '100 kHz'; '200 kHz'; '300 kHz'; '1 MHz'};
            app.MododotraoLabel = uilabel(app.Connection_Grid4);
            app.MododotraoLabel.Text = 'Modo do traço:';
            app.Instr_TraceMode = uitextarea(app.Connection_Grid4);
            app.Instr_TraceMode.Editable = 'off';
            app.Instr_TraceMode.Value = {'Clear Write'; 'Max Hold'; 'Min Hold'; 'Average'};
            app.DetectorLabel = uilabel(app.Connection_Grid4);
            app.DetectorLabel.Text = 'Detector:';
            app.Instr_Detector = uitextarea(app.Connection_Grid4);
            app.Instr_Detector.Editable = 'off';
            app.Instr_Detector.Value = {'Auto Peak'; 'Positive Peak'; 'Negative Peak'; 'Sample'; 'RMS'; 'Average'; 'Quasi Peak'};
            app.Connection_Grid3 = uigridlayout(app.Connection_Grid2);
            app.Instr_Preamp = uicheckbox(app.Connection_Grid3);
            app.Instr_Preamp.Enable = 'off';
            app.Instr_Preamp.Text = '';
            app.Instr_Preamp.Value = true;
            app.Instr_maxLevel = uieditfield(app.Connection_Grid3, 'text');
            app.Instr_maxLevel.Editable = 'off';
            app.Instr_maxLevel.Value = '30 dBm';
            app.PotnciamximaLabel = uilabel(app.Connection_Grid3);
            app.PotnciamximaLabel.Text = 'Potência máxima:';
            app.Instr_Image = uiimage(app.Connection_Grid3);
            app.Instr_Image.ImageSource = '_R&S_FSL6.png';
            app.Instr_Points = uitextarea(app.Connection_Grid3);
            app.Instr_Points.Editable = 'off';
            app.Instr_Points.Value = {'101 - 32001'};
            app.PontosporvarreduraLabel_2 = uilabel(app.Connection_Grid3);
            app.PontosporvarreduraLabel_2.Text = 'Pontos por varredura:';
            app.FaixadefrequnciaLabel = uilabel(app.Connection_Grid3);
            app.FaixadefrequnciaLabel.Text = 'Faixa de frequência:';
            app.Instr_freqRange = uieditfield(app.Connection_Grid3, 'text');
            app.Instr_freqRange.Editable = 'off';
            app.Instr_freqRange.Value = '9 kHz - 6 GHz';
            app.Instr_PreampLabel = uilabel(app.Connection_Grid3);
            app.Instr_PreampLabel.Text = 'Pré-amplificador';
            app.Instr_Description = uilabel(app.Connection_Grid3);
            app.Instr_Description.Text = 'Adquirido entre 2005 e 2006. Alta sensibilidade (em especial quando habilitado o pré-amplificador de 20dB), rapidez (até 2.5ms) e número de pontos por traço configurável, podendo coincidir com canalização da faixa monitorada.';
            app.Connection_Label2 = uilabel(app.Connection_Grid0);
            app.Connection_Label2.Text = 'Principais características';
            app.Connection_Panel1 = uipanel(app.Connection_Grid0);
            app.Connection_Grid1 = uigridlayout(app.Connection_Panel1);
            app.EndereoIPEditField_2Label = uilabel(app.Connection_Grid1);
            app.EndereoIPEditField_2Label.Text = 'Endereço IP:';
            app.Connection_IP = uieditfield(app.Connection_Grid1, 'text');
            app.Connection_IP.Value = '10.71.0.2';
            app.TipodeconexoDropDownLabel = uilabel(app.Connection_Grid1);
            app.TipodeconexoDropDownLabel.Text = 'Tipo de conexão:';
            app.Connection_Type = uidropdown(app.Connection_Grid1);
            app.Connection_Type.Items = {'TCPIP Socket', 'Visa TCPIP'};
            app.Connection_Type.ValueChangedFcn = createCallbackFcn(app, @Connection_TypeValueChanged, true);
            app.Connection_Type.Value = 'TCPIP Socket';
            app.Connection_Name = uidropdown(app.Connection_Grid1);
            app.Connection_Name.Items = {'CRFS RFeye Node 20-6', 'Rohde & Schwarz EB500', 'Rohde & Schwarz FSL-6', 'Rohde & Schwarz FSVR-20'};
            app.Connection_Name.ValueChangedFcn = createCallbackFcn(app, @Connection_NameValueChanged, true);
            app.Connection_Name.Value = 'Rohde & Schwarz FSL-6';
            app.FabricanteemodeloDropDownLabel = uilabel(app.Connection_Grid1);
            app.FabricanteemodeloDropDownLabel.Text = 'Fabricante e modelo';
            app.BufferentradaMBEditFieldLabel = uilabel(app.Connection_Grid1);
            app.BufferentradaMBEditFieldLabel.Text = 'Buffer entrada (MB):';
            app.Connection_Buffer = uieditfield(app.Connection_Grid1, 'numeric');
            app.Connection_Buffer.Limits = [1 Inf];
            app.Connection_Buffer.Value = 2;
            app.Connection_Family = uidropdown(app.Connection_Grid1);
            app.Connection_Family.Items = {'Analisador de espectro / receptor', 'GPS'};
            app.Connection_Family.ValueChangedFcn = createCallbackFcn(app, @Connection_FamilyValueChanged, true);
            app.Connection_Family.Value = 'Analisador de espectro / receptor';
            app.TipoDropDownLabel = uilabel(app.Connection_Grid1);
            app.TipoDropDownLabel.Text = 'Tipo:';
            app.Connection_Port = uieditfield(app.Connection_Grid1, 'text');
            app.Connection_Port.Value = '5025';
            app.PortaEditFieldLabel = uilabel(app.Connection_Grid1);
            app.PortaEditFieldLabel.Text = 'Porta:';
            app.Connection_Timeout = uieditfield(app.Connection_Grid1, 'numeric');
            app.Connection_Timeout.Limits = [1 Inf];
            app.Connection_Timeout.Value = 1;
            app.TimeoutsegEditField_2Label = uilabel(app.Connection_Grid1);
            app.TimeoutsegEditField_2Label.Text = 'Timeout (seg):';
            app.Connection_Description = uieditfield(app.Connection_Grid1, 'text');
            app.DescrioopcionalLabel = uilabel(app.Connection_Grid1);
            app.DescrioopcionalLabel.Text = 'Descrição (opcional):';
            app.Connection_Label1 = uilabel(app.Connection_Grid0);
            app.Connection_Label1.Text = 'Instrumento';
            app.Tab2_Task = uitab(app.TabGroup);
            app.Tab2_Task.Title = 'β';
            app.Task_Grid0 = uigridlayout(app.Tab2_Task);
            app.Task_Grid6 = uigridlayout(app.Task_Grid0);
            app.Task_addTask = uibutton(app.Task_Grid6, 'push');
            app.Task_addTask.Icon = 'RF02.png';
            app.Task_addTask.Enable = 'off';
            app.Task_addTask.Text = 'Editar tarefa';
            app.Tree3 = uitree(app.Task_Grid6);
            app.Tree3.SelectionChangedFcn = createCallbackFcn(app, @Tree3SelectionChanged, true);
            app.Task_deleteBand = uiimage(app.Task_Grid6);
            app.Task_deleteBand.ImageSource = 'adicionar3d.png';
            app.Task_addBand = uiimage(app.Task_Grid6);
            app.Task_addBand.ImageSource = 'adicionar3a.png';
            app.Task_Panel4 = uipanel(app.Task_Grid6);
            app.Task_Grid7 = uigridlayout(app.Task_Panel4);
            app.Task_BandUnit6 = uilabel(app.Task_Grid7);
            app.Task_BandUnit6.Text = 'seg';
            app.Task_BandUnit5 = uilabel(app.Task_Grid7);
            app.Task_BandUnit5.Text = 'seg';
            app.Task_BandUnit4 = uilabel(app.Task_Grid7);
            app.Task_BandUnit4.Text = 'kHz';
            app.FrequnciainicialEditField_2Label = uilabel(app.Task_Grid7);
            app.FrequnciainicialEditField_2Label.Text = 'Frequência inicial:';
            app.ResoluoEditFieldLabel = uilabel(app.Task_Grid7);
            app.ResoluoEditFieldLabel.Text = 'Resolução:';
            app.Task_BandUnit3 = uilabel(app.Task_Grid7);
            app.Task_BandUnit3.Text = 'kHz';
            app.FrequnciafinalEditField_3Label = uilabel(app.Task_Grid7);
            app.FrequnciafinalEditField_3Label.Text = 'Frequência final:';
            app.Task_BandUnit2 = uilabel(app.Task_Grid7);
            app.Task_BandUnit2.Text = 'MHz';
            app.Task_BandUnit1 = uilabel(app.Task_Grid7);
            app.Task_BandUnit1.Text = 'MHz';
            app.ThreadIDEditFieldLabel = uilabel(app.Task_Grid7);
            app.ThreadIDEditFieldLabel.Text = 'ThreadID';
            app.PramplificadorDropDownLabel = uilabel(app.Task_Grid7);
            app.PramplificadorDropDownLabel.Text = 'Pré-amplificador:';
            app.MododoatenuadorDropDownLabel = uilabel(app.Task_Grid7);
            app.MododoatenuadorDropDownLabel.Text = 'Modo do atenuador:';
            app.AtenuadorDropDownLabel = uilabel(app.Task_Grid7);
            app.AtenuadorDropDownLabel.Text = 'Atenuador:';
            app.UnidadeDropDownLabel = uilabel(app.Task_Grid7);
            app.UnidadeDropDownLabel.Text = 'Unidade:';
            app.PassodavarreduraEditField_2Label = uilabel(app.Task_Grid7);
            app.PassodavarreduraEditField_2Label.Text = 'Passo da varredura:';
            app.PontosportraoSpinnerLabel = uilabel(app.Task_Grid7);
            app.PontosportraoSpinnerLabel.Text = 'Pontos por traço:';
            app.MododotraoDropDownLabel = uilabel(app.Task_Grid7);
            app.MododotraoDropDownLabel.Text = 'Modo do traço:';
            app.DetectorDropDownLabel = uilabel(app.Task_Grid7);
            app.DetectorDropDownLabel.Text = 'Detector:';
            app.TempoderevisitaEditFieldLabel = uilabel(app.Task_Grid7);
            app.TempoderevisitaEditFieldLabel.Text = 'Tempo de revisita:';
            app.TempodeintegraoEditFieldLabel = uilabel(app.Task_Grid7);
            app.TempodeintegraoEditFieldLabel.Text = 'Tempo de integração:';
            app.ThresholdLabel = uilabel(app.Task_Grid7);
            app.ThresholdLabel.Text = 'Threshold:';
            app.DescrioEditFieldLabel = uilabel(app.Task_Grid7);
            app.DescrioEditFieldLabel.Text = 'Descrição:';
            app.Task_ThreadID = uieditfield(app.Task_Grid7, 'numeric');
            app.Task_ThreadID.Limits = [1 255];
            app.Task_ThreadID.RoundFractionalValues = 'on';
            app.Task_ThreadID.ValueDisplayFormat = '%.0f';
            app.Task_ThreadID.Value = 1;
            app.Task_Description = uieditfield(app.Task_Grid7, 'text');
            app.Task_Description.Value = 'PMEC 2021 (108-137 MHz)';
            app.Task_F0 = uieditfield(app.Task_Grid7, 'numeric');
            app.Task_F0.ValueDisplayFormat = '%.6f';
            app.Task_F0.ValueChangedFcn = createCallbackFcn(app, @FrequnciainicialEditField_2ValueChanged, true);
            app.Task_F0.Value = 108;
            app.Task_F1 = uieditfield(app.Task_Grid7, 'numeric');
            app.Task_F1.ValueDisplayFormat = '%.6f';
            app.Task_F1.ValueChangedFcn = createCallbackFcn(app, @FrequnciainicialEditField_2ValueChanged, true);
            app.Task_F1.Value = 108;
            app.Task_Resolution = uieditfield(app.Task_Grid7, 'numeric');
            app.Task_Resolution.ValueDisplayFormat = '%.3f';
            app.Task_StepWidth = uieditfield(app.Task_Grid7, 'numeric');
            app.Task_StepWidth.ValueDisplayFormat = '%.3f';
            app.Task_StepWidth.ValueChangedFcn = createCallbackFcn(app, @Task_StepWidthValueChanged, true);
            app.Task_DataPoints = uispinner(app.Task_Grid7);
            app.Task_DataPoints.Step = 100;
            app.Task_DataPoints.Limits = [101 32001];
            app.Task_DataPoints.RoundFractionalValues = 'on';
            app.Task_DataPoints.ValueDisplayFormat = '%.0f';
            app.Task_DataPoints.ValueChangedFcn = createCallbackFcn(app, @Task_DataPointsValueChanged, true);
            app.Task_DataPoints.Value = 101;
            app.Task_TraceMode = uidropdown(app.Task_Grid7);
            app.Task_TraceMode.Items = {'Average', 'ClearWrite', 'MaxHold', 'MinHold'};
            app.Task_TraceMode.Value = 'Average';
            app.Task_Detector = uidropdown(app.Task_Grid7);
            app.Task_Detector.Items = {'Auto Peak', 'Average', 'Negative Peak', 'Positive Peak', 'Quasi Peak', 'RMS', 'Sample'};
            app.Task_Detector.Value = 'Negative Peak';
            app.Task_LevelUnit = uidropdown(app.Task_Grid7);
            app.Task_LevelUnit.Items = {'dBm', 'dBuV', 'dBuV/m'};
            app.Task_LevelUnit.Value = 'dBm';
            app.Task_Preamp = uidropdown(app.Task_Grid7);
            app.Task_Preamp.Items = {'On', 'Off'};
            app.Task_Preamp.Value = 'Off';
            app.Task_attMode = uidropdown(app.Task_Grid7);
            app.Task_attMode.Items = {'Manual', 'Automático'};
            app.Task_attMode.ValueChangedFcn = createCallbackFcn(app, @Task_attModeValueChanged, true);
            app.Task_attMode.Value = 'Automático';
            app.Task_attFactor = uidropdown(app.Task_Grid7);
            app.Task_attFactor.Items = {'0 dB', '5 dB', '10 dB', '15 dB', '20 dB', '25 dB', '30 dB'};
            app.Task_attFactor.Value = '0 dB';
            app.Task_revisitTime = uieditfield(app.Task_Grid7, 'numeric');
            app.Task_revisitTime.ValueDisplayFormat = '%.3f';
            app.Task_integrationTime = uieditfield(app.Task_Grid7, 'numeric');
            app.Task_integrationTime.Limits = [0 Inf];
            app.Task_integrationTime.RoundFractionalValues = 'on';
            app.Task_integrationTime.ValueDisplayFormat = '%.0f';
            app.Task_THR = uieditfield(app.Task_Grid7, 'numeric');
            app.Task_THR.RoundFractionalValues = 'on';
            app.Task_THR.ValueDisplayFormat = '%.0f';
            app.Task_Warning = uiimage(app.Task_Grid6);
            app.Task_Warning.Enable = 'off';
            app.Task_Warning.ImageSource = 'treeAlarm2.png';
            app.Task_Label9 = uilabel(app.Task_Grid6);
            app.Task_Label9.Text = 'Faixas de frequência a monitorar';
            app.Task_Grid2 = uigridlayout(app.Task_Grid0);
            app.NomeEditFieldLabel = uilabel(app.Task_Grid2);
            app.NomeEditFieldLabel.Text = 'Nome:';
            app.CodificaobitsporpontoDropDownLabel = uilabel(app.Task_Grid2);
            app.CodificaobitsporpontoDropDownLabel.Text = 'Codificação (bits por ponto):';
            app.AnalisadordeespectroreceptorDropDownLabel = uilabel(app.Task_Grid2);
            app.AnalisadordeespectroreceptorDropDownLabel.Text = 'Analisador de espectro / receptor:';
            app.GPSDropDownLabel = uilabel(app.Task_Grid2);
            app.GPSDropDownLabel.Text = 'GPS:';
            app.Task_Panel2 = uipanel(app.Task_Grid2);
            app.Task_Grid4 = uigridlayout(app.Task_Panel2);
            app.LatitudeEditFieldLabel = uilabel(app.Task_Grid4);
            app.LatitudeEditFieldLabel.Enable = 'off';
            app.LatitudeEditFieldLabel.Text = 'Latitude:';
            app.Task_manualLongitude = uieditfield(app.Task_Grid4, 'numeric');
            app.Task_manualLongitude.ValueDisplayFormat = '%.6f';
            app.Task_manualLongitude.Enable = 'off';
            app.Task_manualLongitude.Value = -1;
            app.LongitudeEditFieldLabel = uilabel(app.Task_Grid4);
            app.LongitudeEditFieldLabel.Enable = 'off';
            app.LongitudeEditFieldLabel.Text = 'Longitude:';
            app.Task_manualLatitude = uieditfield(app.Task_Grid4, 'numeric');
            app.Task_manualLatitude.ValueDisplayFormat = '%.6f';
            app.Task_manualLatitude.Enable = 'off';
            app.Task_manualLatitude.Value = -1;
            app.Task_GPS = uidropdown(app.Task_Grid2);
            app.Task_GPS.Items = {'Embarcado no instrumento', 'Garmin GPSmap 62s', 'Smartphone', 'Manual'};
            app.Task_GPS.ValueChangedFcn = createCallbackFcn(app, @Task_GPSValueChanged, true);
            app.Task_GPS.Value = 'Garmin GPSmap 62s';
            app.Task_Instrument = uidropdown(app.Task_Grid2);
            app.Task_Instrument.Items = {'CRFS RFeye 20-6', 'Rohde & Schwarz FSL-6', 'Rohde & Schwarz FSVR-20'};
            app.Task_Instrument.ValueChangedFcn = createCallbackFcn(app, @Task_InstrumentValueChanged, true);
            app.Task_Instrument.Value = 'Rohde & Schwarz FSL-6';
            app.Task_Label8 = uilabel(app.Task_Grid2);
            app.Task_Label8.Text = 'Instrumentos';
            app.Task_Panel3 = uipanel(app.Task_Grid2);
            app.Task_Grid5 = uigridlayout(app.Task_Panel3);
            app.AlturadeinstalaoEditFieldLabel = uilabel(app.Task_Grid5);
            app.AlturadeinstalaoEditFieldLabel.Text = 'Altura de instalação:';
            app.AzimuteEditFieldLabel = uilabel(app.Task_Grid5);
            app.AzimuteEditFieldLabel.Enable = 'off';
            app.AzimuteEditFieldLabel.Text = 'Azimute:';
            app.ElevaoEditFieldLabel = uilabel(app.Task_Grid5);
            app.ElevaoEditFieldLabel.Enable = 'off';
            app.ElevaoEditFieldLabel.Text = 'Elevação:';
            app.Task_AntennaUnit3 = uilabel(app.Task_Grid5);
            app.Task_AntennaUnit3.Enable = 'off';
            app.Task_AntennaUnit3.Text = 'graus';
            app.Task_AntennaElevation = uieditfield(app.Task_Grid5, 'numeric');
            app.Task_AntennaElevation.Limits = [0 90];
            app.Task_AntennaElevation.RoundFractionalValues = 'on';
            app.Task_AntennaElevation.ValueDisplayFormat = '%.0f';
            app.Task_AntennaElevation.Enable = 'off';
            app.Task_AntennaUnit2 = uilabel(app.Task_Grid5);
            app.Task_AntennaUnit2.Enable = 'off';
            app.Task_AntennaUnit2.Text = 'graus';
            app.Task_AntennaAzimuth = uieditfield(app.Task_Grid5, 'numeric');
            app.Task_AntennaAzimuth.Limits = [0 359];
            app.Task_AntennaAzimuth.RoundFractionalValues = 'on';
            app.Task_AntennaAzimuth.ValueDisplayFormat = '%.0f';
            app.Task_AntennaAzimuth.Enable = 'off';
            app.Task_AntennaUnit1 = uilabel(app.Task_Grid5);
            app.Task_AntennaUnit1.Text = 'metros';
            app.Task_AntennaHeight = uieditfield(app.Task_Grid5, 'numeric');
            app.Task_AntennaHeight.Limits = [0 127];
            app.Task_AntennaHeight.RoundFractionalValues = 'on';
            app.Task_AntennaHeight.ValueDisplayFormat = '%.0f';
            app.Task_AntennaHeight.Value = 2;
            app.Task_Antenna = uidropdown(app.Task_Grid2);
            app.Task_Antenna.Items = {'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'EB500 (Auto)', 'Rohde & Schwarz ADD107', 'Rohde & Schwarz  ADD207', 'Telescópica'};
            app.Task_Antenna.Value = 'CRFS Low Band (10 MHz - 1.2 GHz)';
            app.AntenaDropDownLabel = uilabel(app.Task_Grid2);
            app.AntenaDropDownLabel.Text = 'Antena:';
            app.Task_Panel1 = uipanel(app.Task_Grid2);
            app.Task_Grid3 = uigridlayout(app.Task_Panel1);
            app.Task_startImediately = uicheckbox(app.Task_Grid3);
            app.Task_startImediately.ValueChangedFcn = createCallbackFcn(app, @Task_startImediatelyValueChanged, true);
            app.Task_startImediately.Text = 'Imediato';
            app.DuraoEditFieldLabel = uilabel(app.Task_Grid3);
            app.DuraoEditFieldLabel.Text = 'Duração:';
            app.Task_DurationUnit = uidropdown(app.Task_Grid3);
            app.Task_DurationUnit.Items = {'seg', 'min', 'horas'};
            app.Task_DurationUnit.Value = 'min';
            app.Task_Duration = uieditfield(app.Task_Grid3, 'numeric');
            app.Task_Duration.Value = 1;
            app.Task_startTimeMinutes = uispinner(app.Task_Grid3);
            app.Task_startTimeMinutes.Step = 10;
            app.Task_startTimeMinutes.Limits = [0 59];
            app.Task_startTimeMinutes.RoundFractionalValues = 'on';
            app.Task_startTimeMinutes.ValueDisplayFormat = '%.0f';
            app.Task_startTimeHours = uispinner(app.Task_Grid3);
            app.Task_startTimeHours.Limits = [0 23];
            app.Task_startTimeHours.RoundFractionalValues = 'on';
            app.Task_startTimeHours.ValueDisplayFormat = '%.0f';
            app.IncioDatePickerLabel = uilabel(app.Task_Grid3);
            app.IncioDatePickerLabel.Text = 'Início:';
            app.Task_startTimeDate = uidatepicker(app.Task_Grid3);
            app.Task_Label7 = uilabel(app.Task_Grid2);
            app.Task_Label7.Text = 'Período de observação';
            app.Task_BitsPerPoint = uidropdown(app.Task_Grid2);
            app.Task_BitsPerPoint.Items = {'8', '16', '32', '64'};
            app.Task_BitsPerPoint.Value = '8';
            app.Task_Name = uieditfield(app.Task_Grid2, 'text');
            app.Task_Name.Editable = 'off';
            app.Task_Name.Value = 'FM e TV_UHF';
            app.Task_Label6 = uilabel(app.Task_Grid2);
            app.Task_Label6.Text = 'Aspectos gerais';
            app.Task_Grid1 = uigridlayout(app.Task_Grid0);
            app.Task_Label5 = uilabel(app.Task_Grid1);
            app.Task_Label5.Text = 'Aguardando início | Em andamento | Finalizada';
            app.Task_Label4 = uilabel(app.Task_Grid1);
            app.Task_Label4.Text = 'Estado:';
            app.Task_Label3 = uilabel(app.Task_Grid1);
            app.Task_Label3.Text = 'Script unico 2021';
            app.Task_Label2 = uilabel(app.Task_Grid1);
            app.Task_Label2.Text = 'Tarefa selecionada:';
            app.Tree2 = uitree(app.Task_Grid0);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            app.Scriptunico2021Node = uitreenode(app.Tree2);
            app.Scriptunico2021Node.Icon = 'REC1a.png';
            app.Scriptunico2021Node.Text = 'Script unico 2021';
            app.FMeTV_UHFNode = uitreenode(app.Tree2);
            app.FMeTV_UHFNode.Text = 'FM e TV_UHF';
            app.Task_deleteTask = uiimage(app.Task_Grid0);
            app.Task_deleteTask.ImageSource = 'adicionar3d.png';
            app.Task_Label1 = uilabel(app.Task_Grid0);
            app.Task_Label1.Text = 'Lista de tarefas';
            app.Tab3_Spec = uitab(app.TabGroup);
            app.Tab3_Spec.Title = 'γ';
            app.ToolBar_Grid = uigridlayout(app.Grid1);
            app.ToolBar_Button5 = uibutton(app.ToolBar_Grid, 'push');
            app.ToolBar_Button5.Icon = 'ChangeLayout2.png';
            app.ToolBar_Button5.Enable = 'off';
            app.ToolBar_Button5.Text = '';
            app.ToolBar_Button4 = uibutton(app.ToolBar_Grid, 'push');
            app.ToolBar_Button4.Icon = 'global-icon-13.png';
            app.ToolBar_Button4.Enable = 'off';
            app.ToolBar_Button4.Text = '';
            app.ToolBar_Button3 = uibutton(app.ToolBar_Grid, 'push');
            app.ToolBar_Button3.Icon = 'settings2.png';
            app.ToolBar_Button3.Enable = 'off';
            app.ToolBar_Button3.Text = '';
            app.ToolBar_Button2 = uibutton(app.ToolBar_Grid, 'push');
            app.ToolBar_Button2.Icon = 'InfoIcon7.png';
            app.ToolBar_Button2.Enable = 'off';
            app.ToolBar_Button2.Text = '';
            app.ToolBar_Button1 = uibutton(app.ToolBar_Grid, 'push');
            app.ToolBar_Button1.Icon = 'Help.png';
            app.ToolBar_Button1.Enable = 'off';
            app.ToolBar_Button1.Text = '';
            app.menu_Panel = uipanel(app.Grid1);
            app.menu_Grid0 = uigridlayout(app.menu_Panel);
            app.menu_Grid3 = uigridlayout(app.menu_Grid0);
            app.menu_searchPath = uiimage(app.menu_Grid3);
            app.menu_searchPath.ImageSource = 'Zoom4.png';
            app.menu_userPath = uidropdown(app.menu_Grid3);
            app.menu_userPath.Items = {'Path'};
            app.menu_userPath.Value = 'Path';
            app.menu_Grid1 = uigridlayout(app.menu_Grid0);
            app.menu_Button9 = uibutton(app.menu_Grid1, 'state');
            app.menu_Button9.Enable = 'off';
            app.menu_Button9.Icon = 'REC1a.png';
            app.menu_Button9.Text = '';
            app.menu_Separator2 = uiimage(app.menu_Grid1);
            app.menu_Separator2.ImageSource = 'Line.png';
            app.menu_Separator1 = uiimage(app.menu_Grid1);
            app.menu_Separator1.ImageSource = 'Line.png';
            app.menu_Grid2 = uigridlayout(app.menu_Grid1);
            app.menu_Button8 = uibutton(app.menu_Grid2, 'state');
            app.menu_Button8.Enable = 'off';
            app.menu_Button8.Icon = 'Plot.png';
            app.menu_Button8.Text = '';
            app.menu_Button7 = uibutton(app.menu_Grid2, 'state');
            app.menu_Button7.Enable = 'off';
            app.menu_Button7.Icon = 'Task2.png';
            app.menu_Button7.Text = '';
            app.menu_Button6 = uibutton(app.menu_Grid2, 'state');
            app.menu_Button6.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.menu_Button6.Enable = 'off';
            app.menu_Button6.Icon = 'Task3.png';
            app.menu_Button6.Text = '';
            app.menu_Button5 = uibutton(app.menu_Grid2, 'state');
            app.menu_Button5.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.menu_Button5.Enable = 'off';
            app.menu_Button5.Icon = 'Task4.png';
            app.menu_Button5.Text = '';
            app.menu_Button4 = uibutton(app.menu_Grid2, 'push');
            app.menu_Button4.Icon = 'Task1.png';
            app.menu_Button4.Enable = 'off';
            app.menu_Button4.Text = '';
            app.menu_Button3 = uibutton(app.menu_Grid1, 'state');
            app.menu_Button3.ValueChangedFcn = createCallbackFcn(app, @menu_Button3ValueChanged, true);
            app.menu_Button3.Icon = 'RF02.png';
            app.menu_Button3.Text = '';
            app.menu_Button2 = uibutton(app.menu_Grid1, 'state');
            app.menu_Button2.ValueChangedFcn = createCallbackFcn(app, @menu_Button2ValueChanged, true);
            app.menu_Button2.Icon = 'clock.png';
            app.menu_Button2.Text = '';
            app.menu_Button1 = uibutton(app.menu_Grid1, 'state');
            app.menu_Button1.ValueChangedFcn = createCallbackFcn(app, @menu_Button1ValueChanged, true);
            app.menu_Button1.Icon = 'Connect.png';
            app.menu_Button1.Text = '';
            app.menu_Button1.Value = true;
            % Show the figure after all components are created
            app.MainApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appColeta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainApp)
        end
    end
end