classdef winMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        mainGrid            matlab.ui.container.GridLayout
        toolGrid            matlab.ui.container.GridLayout
        Button_play         matlab.ui.control.Button
        SettingsInfo        matlab.ui.control.Label
        Button_info         matlab.ui.control.Button
        Panel_main          matlab.ui.container.Panel
        Grid_main           matlab.ui.container.GridLayout
        Table_Grid          matlab.ui.container.GridLayout
        Column7             matlab.ui.control.Label
        Column6             matlab.ui.control.Label
        Column5             matlab.ui.control.Label
        Column4             matlab.ui.control.Label
        Column3             matlab.ui.control.Label
        Column2             matlab.ui.control.Label
        Column1             matlab.ui.control.Label
        Table               matlab.ui.control.Table
        Tree                matlab.ui.container.CheckBoxTree
        TreeLabel           matlab.ui.control.Label
        RadioGroup          matlab.ui.container.ButtonGroup
        Option2             matlab.ui.control.RadioButton
        Option1             matlab.ui.control.RadioButton
        RadioGroupLabel     matlab.ui.control.Label
        Subtitle            matlab.ui.control.Label
        Panel_info2         matlab.ui.container.Panel
        Grid_info2          matlab.ui.container.GridLayout
        Qtd_Unknown         matlab.ui.control.Label
        Qtd_UnknownLabel    matlab.ui.control.Label
        Panel_info1         matlab.ui.container.Panel
        Grid_info1          matlab.ui.container.GridLayout
        Qtd_Emissions       matlab.ui.control.Label
        Qtd_EmissionsLabel  matlab.ui.control.Label
        Title               matlab.ui.control.Label
        Image               matlab.ui.control.Image
    end

    
    properties (Access = private)
        CallingApp
        RootFolder

        ftpObj

        % Thresholds:
        % - THR1: Valor absoluto que indica a quantidade média de rompimentos de mascára espectral em relação ao número de
        % traços capturados enquanto se esperava as x mensagens do tipo 4 (x definido por BrokenMsg).
        % - THR2: Valor percentual usado para identificar as emissões persistentes (uma espécia de ocupação mínima).
        General

        UnMaskedEmissions   = table('Size', [0 11],                                                                                                     ...
                                    'VariableTypes', {'cell', 'cell', 'cell', 'double', 'double', 'uint32', 'int32', 'cell', 'cell', 'cell', 'string'}, ...
                                    'VariableNames', {'State', 'City', 'Node', 'Freq', 'Truncated', 'BW', 'OCC', 'Description', 'Distance', 'TimeStamp', 'Tag'});
        PersistentEmissions = table('Size', [0 11],                                                                                                     ...
                                    'VariableTypes', {'cell', 'cell', 'cell', 'double', 'double', 'uint32', 'int32', 'cell', 'cell', 'cell', 'string'}, ...
                                    'VariableNames', {'State', 'City', 'Node', 'Freq', 'Truncated', 'BW', 'OCC', 'Description', 'Distance', 'TimeStamp', 'Tag'});
        UnMaskedWebSheet
        PersistentWebSheet

        RFeyeList        
        maskStruct = struct('raw', {}, 'freq', {}, 'FreqStart', {}, 'FreqStop', {}, 'udpPort', {});
        bandList
    end
    
    
    methods (Access = private)

        function Fcn1_OpenFile(app, FilePath)
            [tempList, msg] = OpenFile_rfeyeList(FilePath);

            if ~isempty(msg)
                MessageBox(app, 'warning', msg)

            else
                tempList.Monitor = repmat({''}, height(tempList), 1);

                for ii = 1:height(tempList)
                    if ~isempty(tempList.Location{ii})
                        tempList.State{ii} = tempList.Location{ii}(end-1:end);
                    end
                end
            end

            app.RFeyeList = tempList;
        end


        function Fcn2_MonitorSettings(app)
            app.General = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'Monitor.json')));
            
            app.General.Bands = struct2table(app.General.Bands);
            app.General.GarbageMask = string(app.General.GarbageMask);

            if ~isfile(app.General.WebSheetPath)
                app.General.WebSheetPath = fullfile(app.RootFolder, 'Settings', 'monitorGeral.xlsx');
            end

            app.SettingsInfo.Text = jsonencode(app.General);
        end


        function Fcn3_TreeBuilding(app)            
            delete(app.Tree.Children)
            
            States = unique(app.RFeyeList.State(logical(app.RFeyeList.Enable)));
            for ii = 1:height(States)
                idx = find(strcmp(app.RFeyeList.State, States{ii}));
                ParentNode = uitreenode(app.Tree, 'Text', States{ii}, 'NodeData', idx);

                for jj = idx'
                    uitreenode(ParentNode, 'Text', sprintf('%s - %s', app.RFeyeList.Name{jj}, app.RFeyeList.Location{jj}), 'NodeData', jj);
                end
                expand(ParentNode)
            end
            addStyle(app.Tree, uistyle("FontWeight", "bold"), 'level', 1)      
        end


        function Misc_integraImport(app, TagMode)
            switch TagMode
                case "EMISSÕES DESMASCARADAS"
                    opt = spreadsheetImportOptions("Sheet", "UnMasked", ...
                                                   "NumVariables", 13,  ...
                                                   "VariableTypes", ["int32", "string", "double", "double", "double", "string", "int16", "int32", "string", "string", "string", "string", "datetime"],                ...
                                                   "VariableNames", ["ID", "Node", "Frequency", "Truncated", "BW", "Regulatory", "Service", "Station", "Description", "Status", "InMask", "Uploaded", "dateCreated"], ...
                                                   "DataRange", "A2");
                    
                    app.UnMaskedWebSheet  = readtable(app.General.WebSheetPath, opt);                

                case "EMISSÕES PERSISTENTES"
                    opt = spreadsheetImportOptions("Sheet", "Persistent", ...
                                                   "NumVariables", 13,    ...
                                                   "VariableTypes", ["int32", "string", "double", "double", "double", "double", "string", "int16", "int32", "string", "string", "string", "datetime"],             ...
                                                   "VariableNames", ["ID", "Node", "Frequency", "Truncated", "BW", "OCC", "Regulatory", "Service", "Station", "Description", "Status", "Uploaded", "dateCreated"], ...
                                                   "DataRange", "A2");

                    app.PersistentWebSheet  = readtable(app.General.WebSheetPath, opt);
            end
        end


        function Misc_ScriptAndMaskFiles(app, ind)
            
            cd(app.ftpObj,   '/etc/drops/');
            mget(app.ftpObj, 'rfeyed.cfg', app.RootFolder);

            scriptFilename = sprintf('%s_script.cfg', app.RFeyeList.Name{ind});
            movefile(fullfile(app.RootFolder, 'rfeyed.cfg'), fullfile(app.RootFolder, 'Temp', scriptFilename));

            maskFileNames = app.CallingApp.logger_ScriptRead(fullfile(app.RootFolder, 'Temp', scriptFilename));
            for ii = 1:numel(maskFileNames)
                if ismember(maskFileNames{ii}, app.General.GarbageMask)
                    continue
                end

                cd(app.ftpObj,   '/etc/drops/logger_masks/');
                mget(app.ftpObj, maskFileNames{ii}, app.RootFolder);

                maskFileName = sprintf('%s_%s', app.RFeyeList.Name{ind}, maskFileNames{ii});
                movefile(fullfile(app.RootFolder, maskFileNames{ii}), fullfile(app.RootFolder, 'Temp', maskFileName));
                
                Misc_MaskFileRead(app, scriptFilename, maskFileName)
            end

        end


        function Misc_MaskFileRead(app, scriptFilename, maskFileName)

            scriptFilename = fullfile(app.RootFolder, 'Temp', scriptFilename);
            maskFileName   = fullfile(app.RootFolder, 'Temp', maskFileName);

            inData = readtable(maskFileName, 'VariableNamingRule', 'preserve');
            inData.Properties.VariableNames{1} = 'Start';
            inData.Properties.VariableNames{2} = 'Stop';

            app.maskStruct = struct('raw', inData, 'freq', [], 'FreqStart', [], 'FreqStop', [], 'udpPort', []);
                     
            app.maskStruct.FreqStart = inData.Start(1);
            app.maskStruct.FreqStop  = inData.Stop(end);
             
            bw = inData.Start(2:end) - inData.Stop(1:end-1);
            app.maskStruct.freq = [(inData.Stop(1:end-1) + bw/2)  bw];

            udpPorts = regexpi(fileread(scriptFilename), '\w*\s*=\s*udp\s*[,]*\s*(?<Port>\d*)', 'names');
            if isempty(udpPorts) | isempty(udpPorts(1).Port)
                app.maskStruct.udpPort = 8910;
            else
                app.maskStruct.udpPort = str2double(udpPorts(1).Port);
            end

        end

        
        function Misc_SaveLog(app)

            indFind = find(~strcmp(app.RFeyeList.Monitor, ''));
            if ~isempty(indFind)
                fileID = fopen(fullfile(app.RootFolder, 'Temp', sprintf('%s_Log.txt', datestr(now, 'yyyymmdd_HHMMSS'))), "wt");

                logicalArray = contains(app.RFeyeList.Monitor(indFind), 'matlab.io');
                SuccessNodes = strjoin(app.RFeyeList.Name(indFind(find( logicalArray))), ', ');
                if isempty(SuccessNodes)
                    SuccessNodes = '-';
                end

                UnSuccessNodes = strjoin(app.RFeyeList.Name(indFind(find(~logicalArray))), ', ');
                if isempty(UnSuccessNodes)
                    UnSuccessNodes = '-';
                end
    
                fullLog = sprintf(['appColeta v. %s (Monitor)\n' ... 
                                   '%s\n'                        ...
                                   '- Sensores que apresentaram conectividade: %s\n'        ...
                                   '- Sensores que não apresentaram conectividade: %s\n\n', ...
                                   '%s'], app.CallingApp.Version.appColeta,    ...
                                          datestr(now, 'dd/mm/yyyy HH:MM:SS'), ...
                                          SuccessNodes,  ...
                                          UnSuccessNodes, ...
                                          replace(jsonencode(app.RFeyeList(indFind, [1,22]), 'PrettyPrint', true), '\\fontname{Calibri}\\fontsize{10}', ''));

                fwrite(fileID, fullLog, 'char');
                fclose(fileID);
            end

        end

        
        function Peaks = UnMasked_PeaksTable(app, Peaks)
            if ~isempty(app.maskStruct)
                for ii = 1:height(app.maskStruct.freq)
                    if app.maskStruct.freq(ii,2) && ~isempty(intersect(find(app.General.Bands.FreqStart <= app.maskStruct.freq(ii,1)), ...
                                                                       find(app.General.Bands.FreqStop  >= app.maskStruct.freq(ii,1))))
                        Peaks(end+1,:) = {app.maskStruct.freq(ii,1),           ...
                                          -1, ceil(app.maskStruct.freq(ii,2)), ...
                                          -1, -1, -1, '-', '-'};
                    end
                end

                Peaks.Truncated = Misc_Truncated(app, Peaks.Frequency, app.maskStruct.FreqStart, app.maskStruct.FreqStop);
            end
        end


        function Peaks = udpMonitor_udpLoggerRead(app, Peaks, Node, d)

            % LOCAL VARIABLES
            ThreadIDList   = app.General.Persistent.ThreadIDList;
            BrokenMsg      = app.General.Persistent.BrokenMsg;
            ScansPerMinute = app.General.Persistent.ScansPerMinute;
            THR1_Global    = app.General.Persistent.THR1_Global;
            
            app.bandList   = [];
            countBroken    = {repmat(datetime([0 0 0 0 0 0], 'Format', 'dd/MM/yyyy HH:mm:ss'), 0, 0), [], []};

            ReceivedDatagrams = 0;
            nZeroDatagrams    = 0;

            udpPortArray = app.CallingApp.udpPortArray;
            IP           = app.ftpObj.Host;
            Port         = app.maskStruct.udpPort;            
            
            udpConfig    = app.CallingApp.General.logger;
            udpConfig.ConnectionTime = ScansPerMinute*BrokenMsg;

            % UDP PORT
            [app.CallingApp.udpPortArray, ind] = connect_udpSockets(udpPortArray, Port, udpConfig.Timeout);
            udpPort = app.CallingApp.udpPortArray{ind};
            flush(udpPort)
            
            % DATAGRAMS READING PROCESS
            while true
                if ~udpPort.NumDatagramsAvailable
                    udpMonitor_OpenConnection(app, udpPort, udpConfig, IP, Port)
        
                    if ~udpPort.NumDatagramsAvailable
                        nZeroDatagrams = nZeroDatagrams + 1;
                        if nZeroDatagrams == 3
                            error(struct('message', sprintf('Não recebidos datagramas do sensor %s', Node)))
                        end
                    end
                    
                elseif udpPort.NumDatagramsAvailable
                    ReceivedDatagrams = ReceivedDatagrams + udpPort.NumDatagramsAvailable;
                    d.Message = sprintf('%.0f datagramas recebidos...', ReceivedDatagrams);
                    drawnow

                    SpecInfo = read(udpPort, udpPort.NumDatagramsAvailable, 'uint8');

                    for ii = 1:numel(SpecInfo)
                        if ~strcmp(SpecInfo(ii).SenderAddress, IP) || numel(SpecInfo(ii).Data) < 96
                            continue
                        end
        
                        MagicNumber = typecast(uint8((SpecInfo(ii).Data(1:4))), 'uint32');
                        ThreadID    = typecast(uint8(SpecInfo(ii).Data(9:12)),  'uint32');
                        DataType    = typecast(uint8(SpecInfo(ii).Data(17:20)), 'int32');
                        EOFMarker   = char(SpecInfo(ii).Data(end-3:end));
                        
                        if (MagicNumber == 1193046) && strcmp(EOFMarker, 'UUUU')
                            if DataType == 42
                                outInfo   = logger_DatagramRead(DataType, SpecInfo(ii).Data);
                                TimeStamp = datetime(outInfo{1}, 'InputFormat', 'dd/MM/yyyy HH:mm:ss');
                                FreeText  = outInfo{3};

                                try
                                    jsonStruct = jsondecode(FreeText);
                                    if jsonStruct.type == 4
                                        countBroken{1}(end+1)   = TimeStamp;
                                        countBroken{2}          = jsonStruct.fields;
                                        countBroken{3}(:,end+1) = jsonStruct.values;
                                    end
                                catch
                                end

                            elseif ismember(DataType, [67,68]) & ismember(ThreadID, ThreadIDList) 
                                outInfo  = logger_DatagramRead(DataType, SpecInfo(ii).Data, ThreadID);
                                Bin      = outInfo{1};
                                newArray = outInfo{2};

                                Bin.WritedTraces = 0;
                                Bin.Traces       = [];

                                udpMonitor_BandListUpdate(app, Bin, newArray)
                            end    
                        end    
                    end
                end
                
                if ~isempty(countBroken{1})
                    if minutes(countBroken{1}(end)-countBroken{1}(1)) >= BrokenMsg
                        break
                    end
                end
                pause(0.1)
            end
            
            if isempty(app.bandList)
                return
            else
                if ~issorted([app.bandList.ThreadID])
                    [~, indSort] = sort([app.bandList.ThreadID]);
                    app.bandList = app.bandList(indSort);
                end
            end

            countBroken_Mean = ceil((countBroken{3}(:,end)-countBroken{3}(:,1))/minutes(countBroken{1}(end)-countBroken{1}(1)));
            indThreadID      = find(countBroken_Mean > THR1_Global);

            Misc_LogWrite(app, Node, sprintf('CONTADOR MÉDIO DE ROMPIMENTO DE MÁSCARA:\n%s', ...
                          jsonencode(struct('beginTime', countBroken{1}(1), 'endTime', countBroken{1}(end), ...
                                            'countBroken_Mean', [countBroken{2}, countBroken_Mean]))))

            for ii = indThreadID'
                idx = find([app.bandList.ThreadID] == countBroken{2}(ii), 1);

                if ~isempty(idx)
                    F0 = app.bandList(idx).FreqStart/1000;
                    F1 = app.bandList(idx).FreqStop/1000;
                    
                    maskArray = ones([app.bandList(idx).DataPoints, 1])*1000;
                    maskFreq  = linspace(F0, F1, app.bandList(idx).DataPoints)';
                            
                    for jj = 1:height(app.maskStruct.freq)
                        maskArray(maskFreq >= (app.maskStruct.raw.Start(jj) - app.bandList(idx).StepWidth/2000) & ...
                                  maskFreq <= (app.maskStruct.raw.Stop(jj)  + app.bandList(idx).StepWidth/2000)) = app.maskStruct.raw.Threshold(jj);
                    end
    
                    occMat = zeros([app.bandList(idx).DataPoints app.bandList(idx).WritedTraces], 'single');
                    occMat(bsxfun(@gt, app.bandList(idx).Traces(:, 1:app.bandList(idx).WritedTraces), maskArray)) = 1;
                    occMatEdited = sum(occMat, 2)/app.bandList(idx).WritedTraces;                
    
                    Peaks = udpMonitor_PeaksTable(app, Peaks, maskFreq, occMatEdited, idx);
                end
            end
            
        end


        function udpMonitor_OpenConnection(app, udpPort, udpConfig, IP, Port)
            
            AvailableDatagrams_Initial = udpPort.NumDatagramsAvailable;

            tic
            t1 = toc;
            while t1 <= udpConfig.Timeout
                write(udpPort, sprintf('open %.0f', udpConfig.ConnectionTime), 'uint8', IP, Port);
                pause(udpConfig.HandshakePause)

                if udpPort.NumDatagramsAvailable > AvailableDatagrams_Initial
                    break
                end
                t1 = toc;
            end        
        end


        function udpMonitor_BandListUpdate(app, Bin, newArray)

            BrokenMsg      = app.General.Persistent.BrokenMsg;
            ScansPerMinute = app.General.Persistent.ScansPerMinute;

            if isempty(app.bandList)
                app.bandList = Bin;
                app.bandList.Traces = zeros(Bin.DataPoints, ScansPerMinute*BrokenMsg, 'single');

                indCurr   = 1;
            else
                indFind = find([app.bandList.ThreadID] == Bin.ThreadID);
                indCurr = [];

                for ii = indFind
                    if isequal(rmfield(app.bandList(ii), {'WritedTraces', 'Traces'}), rmfield(Bin, {'WritedTraces', 'Traces'}))
                        indCurr = ii;
                        break
                    end
                end

                if isempty(indCurr)
                    app.bandList(end+1) = Bin;
                    app.bandList(end).Traces = zeros(Bin.DataPoints, ScansPerMinute*BrokenMsg, 'single');

                    indCurr = numel(app.bandList);
                end
            end
            
            app.bandList(indCurr).WritedTraces = app.bandList(indCurr).WritedTraces + 1;
            app.bandList(indCurr).Traces(:, app.bandList(indCurr).WritedTraces) = newArray;

        end


        function Peaks = udpMonitor_PeaksTable(app, Peaks, maskFreq, occMatEdited, idx)
            freqList = [];
            indFreq  = find(occMatEdited);
            THR2_OCC = app.General.Persistent.THR2_OCC;

            for ii = indFreq'
                refFreq = [ii, 1];
            
                if isempty(freqList) || refFreq(1) ~= sum(freqList(end,:))
                    freqList(end+1,:) = refFreq;
                else
                    freqList(end,2)   = freqList(end,2)+1;
                end
            end
            
            if ~isempty(freqList)
                for jj = 1:height(freqList)
                    [Max, indMax] = max(occMatEdited(freqList(jj,1):sum(freqList(jj,1:2))-1));

                    freqList(jj, 1) = maskFreq(freqList(jj, 1)+indMax-1);
                    freqList(jj, 2) = (freqList(jj, 2) + 1) * app.bandList(idx).StepWidth/1000;
                    freqList(jj, 3) = Max;
                end
                freqList(freqList(:,3) < THR2_OCC, :) = [];
                Truncated = Misc_Truncated(app, freqList(:,1), app.maskStruct.FreqStart, app.maskStruct.FreqStop);
    
                for ii = 1:height(freqList)
                    Peaks(ii,:) = {freqList(ii,1), Truncated(ii), ceil(freqList(ii,2)), freqList(ii,3)*100, -1, -1, '-', '-'};
                end
            end
        end


        function Misc_ftpObject(app, idx)
            IPs  = {app.RFeyeList.IP1{idx}, app.RFeyeList.IP2{idx}};
            Type = {'ftp', 'sftp'}; 

            app.ftpObj = [];
            for ii = 1:numel(IPs)
                for jj = 1:numel(Type)
                    try
                        switch Type{jj}
                            case  'ftp'; app.ftpObj =  ftp(sprintf('%s:%s', IPs{ii}, app.RFeyeList.ftpPort),  'root', app.RFeyeList.rootPass{idx});
                            case 'sftp'; app.ftpObj = sftp(sprintf('%s:%s', IPs{ii}, app.RFeyeList.sftpPort), 'root', 'Password', app.RFeyeList.rootPass{idx});
                        end
                        Misc_LogWrite(app, app.RFeyeList.Name{idx}, sprintf('%s (%s:%.0f)', class(app.ftpObj), app.ftpObj.Host, app.ftpObj.Port))
                        return

                    catch ME
                        Misc_LogWrite(app, app.RFeyeList.Name{idx}, ME.message)
                    end
                end
            end
        end


        function gps = Misc_gps(app, webPass)
            try
                status  = webread(sprintf('http://%s/cgi-bin/status.cgi', app.ftpObj.Host), weboptions('Username', 'admin', 'Password', webPass));
                gps_str = strsplit(status.GPS);

                gps = struct('Latitude',  str2double(gps_str{6})/1e+6, ...
                             'Longitude', str2double(gps_str{7})/1e+6);
            catch
            end
        end


        function Misc_LogWrite(app, Node, msg)
            ind = find(strcmp(app.RFeyeList.Name, Node), 1);

            if isempty(app.RFeyeList.Monitor{ind})
                app.RFeyeList.Monitor{ind} = msg;
            else
                app.RFeyeList.Monitor{ind} = sprintf('%s\n%s', app.RFeyeList.Monitor{ind}, msg);
            end
        end


        function Misc_TreeNodeColor(app, Node, Flag)
            if Flag; imgColor = "LT_CircleGreen.png";
            else;    imgColor = "LT_CircleRed.png";
            end

            ind1 = [];
            ind2 = [];
            for ii = 1:numel(app.Tree.Children)
                for jj = 1:numel(app.Tree.Children(ii).Children)
                    if contains(app.Tree.Children(ii).Children(jj).Text, Node)
                        ind1 = ii;
                        ind2 = jj;
                        break
                    end
                end

                if ~isempty(ind1)
                    app.Tree.Children(ind1).Children(ind2).Icon = imgColor;
                    break
                end
            end
        end


        function TruncatedPeaks = Misc_Truncated(app, FrequencyPeaks, F0, F1)
            global peaksReference
            if isempty(peaksReference)
                Misc_FindPeaksDefaultValues(app.RootFolder)
            end
            
            TruncatedPeaks = [];
            
            for ii = numel(FrequencyPeaks):-1:1
                ind = [];
                for jj = 1:numel(peaksReference)
                    if (peaksReference(jj).Band(1)/1000 <= FrequencyPeaks(ii)) && (peaksReference(jj).Band(2)/1000 >= FrequencyPeaks(ii))
                        ind = jj;
                        break
                    end
                end
                
                if ~isempty(ind)
                    FrequencyChannels = peaksReference(ind).Channels .* 1000;
                else
                    FrequencyChannels = F0:25:F1;
                end

                [~, auxIndex]        = min(abs(FrequencyChannels - FrequencyPeaks(ii)));
                TruncatedPeaks(ii,1) = FrequencyChannels(auxIndex);                
            end            
        end
        
        
        function Peaks = Misc_closestStation(app, Peaks, GPS)      

            global AnatelDB
            global ExceptionGlobalList

            idx0 = find((abs(ExceptionGlobalList.Frequency - Peaks.Truncated/1000) <= 1e-5), 1);
            if ~isempty(idx0)
                Peaks(1,7:8) = {ExceptionGlobalList.Description{idx0}, 'Inf'};
                return

            else
                idx1 = find(abs(AnatelDB.Frequency - Peaks.Truncated/1000) <= 1e-5);            
                if ~isempty(idx1)
                    auxDistance = geo_lldistkm_v2(struct('lat', GPS.Latitude, 'lon', GPS.Longitude), AnatelDB(idx1, 3:4));                
                    [Distance, idx2] = min(auxDistance);
    
                    if Distance <= app.General.Contour
                        Peaks(1,5:8) = {AnatelDB.Service(idx1(idx2)), ...
                                        AnatelDB.Station(idx1(idx2)), ...
                                        char(AnatelDB.Description(idx1(idx2))), ...
                                        sprintf('%.1f', Distance)};
                    end
                end  
            end            
          
        end


        function tempSheet_Web = Misc_AddInfoToSheet(app, TagMode, tempSheet_Web, rfNode, Peaks, position, beginTime)
            if Peaks.Service == -1; RegulatoryInfo = "Não licenciado";             
            else;                   RegulatoryInfo = "Licenciado";
            end

            if position <= height(tempSheet_Web)
                Uploaded    = tempSheet_Web.Uploaded(position);
                Status      = tempSheet_Web.Status(position);
                dateCreated = tempSheet_Web.dateCreated(position);
            else
                Uploaded    = "Não";
                if Peaks.Description{1} == "-"; Status = "Pendente identificação";
                else;                           Status = "Pendente confirmação";
                end
                dateCreated = beginTime;                                
            end   

            switch TagMode
                case "EMISSÕES DESMASCARADAS"
                    tempSheet_Web(position,:) = {position, rfNode, round(Peaks.Frequency/1000, 3), round(Peaks.Truncated/1000, 3), ...
                                                 Peaks.BW, RegulatoryInfo, Peaks.Service, Peaks.Station, Peaks.Description{1},     ...
                                                 Status, "Sim", Uploaded, dateCreated};   
                case "EMISSÕES PERSISTENTES"
                    tempSheet_Web(position,:) = {position, rfNode, round(Peaks.Frequency/1000, 3), round(Peaks.Truncated/1000, 3),        ...
                                                 Peaks.BW, Peaks.OCC, RegulatoryInfo, Peaks.Service, Peaks.Station, Peaks.Description{1}, ...
                                                 Status, Uploaded, dateCreated};
            end
        end


        function Misc_integraUpdate(app, TagMode, log)
            try
                switch TagMode
                    case "EMISSÕES DESMASCARADAS"
                        writetable(app.UnMaskedWebSheet,   app.General.WebSheetPath, 'Sheet', 'UnMasked',       'WriteVariableNames', false, 'Range', 'A2', 'AutoFitWidth', false, 'PreserveFormat', true);
                        writecell(log,                     app.General.WebSheetPath, 'Sheet', 'UnMasked_Log',   'WriteMode', 'Append',                      'AutoFitWidth', false, 'PreserveFormat', true);
                    case "EMISSÕES PERSISTENTES"
                        writetable(app.PersistentWebSheet, app.General.WebSheetPath, 'Sheet', 'Persistent',     'WriteVariableNames', false, 'Range', 'A2', 'AutoFitWidth', false, 'PreserveFormat', true);
                        writecell(log,                     app.General.WebSheetPath, 'Sheet', 'Persistent_Log', 'WriteMode', 'Append',                      'AutoFitWidth', false, 'PreserveFormat', true);
                end

            catch ME
                msg = sprintf(['<font style="font-size:12;"><b>Não foi possível salvar as informações na planilha ' ...
                               'de controle pelas razões indicadas a seguir. Deseja tentar novamente?</b>\n\n%s'], ME.identifier);

                Selection = uiconfirm(app.UIFigure, msg, 'appColeta', 'Options', {'Sim', 'Não'}, 'Interpreter', 'html');
                if Selection == "Sim"
                    Misc_integraUpdate(app, TagMode, log)
                end
            end
        end

       
       function MessageBox(app, type, msg)
            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
 
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            Fcn1_OpenFile(app, fullfile(app.RootFolder, 'Settings', 'rfeyeList.cfg'));
            Fcn2_MonitorSettings(app)
            Fcn3_TreeBuilding(app)

        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            
            app.CallingApp.auxWin5 = [];
            delete(app)
            
        end

        % Button pushed function: Button_info
        function Button_infoPushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winMonitor>', '</winMonitor>')));

            MessageBox(app, 'info', msg)

        end

        % Button pushed function: Button_play
        function Button_playPushed(app, event)
            
            idx = [];
            for ii = 1:numel(app.Tree.CheckedNodes)
                idx = [idx; app.Tree.CheckedNodes(ii).NodeData];
            end
            idx = unique(idx);

            if isempty(idx)
                MessageBox(app, 'warning', 'É preciso selecionar ao menos um sensor.')
                return
            end
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Cancelable', 'on', 'CancelText', 'Cancelar', 'Interpreter', 'html');
            
            for ii = idx'
                try
                    app.RFeyeList.Monitor{ii} = '';

                    beginTime = datestr(now, 'dd/mm/yyyy HH:MM:SS');
                    Peaks     = table('Size', [0, 8],                                                                             ...
                                      'VariableTypes', {'double', 'double', 'uint32', 'int32', 'int16', 'int32', 'cell', 'cell'}, ...
                                      'VariableNames', {'Frequency', 'Truncated', 'BW', 'OCC', 'Service', 'Station', 'Description', 'Distance'});

                    if     app.Option1.Value; TagMode = "EMISSÕES DESMASCARADAS";
                    elseif app.Option2.Value; TagMode = "EMISSÕES PERSISTENTES";
                    end

                    % INTEGRA SHEET
                    Misc_integraImport(app, TagMode)

                    % FTP/SFTP OBJECT
                    set(d, 'Title', app.RFeyeList.Name{ii}, 'Message', 'Em andamento tentativa de criar objeto FTP/SFTP...')
                    Misc_ftpObject(app, ii);
                    if isempty(app.ftpObj)
                        Misc_TreeNodeColor(app, app.RFeyeList.Name{ii}, false)
                        continue
                    end
    
                    % SPECTRAL MASK (DOWNLOAD/READ)
                    d.Message = 'Em andamento tentativa de <i>download</i> dos arquivos de <i>script</i> e máscara espectral carregados no sensor...';
                    Misc_ScriptAndMaskFiles(app, ii);
    
                    % GPS
                    d.Message = 'Em andamento tentativa de atualizar os dados de latitude e longitude do local da monitoração...';
                    gps = Misc_gps(app, app.RFeyeList.webPass{ii});
                    if ~isempty(gps)
                        app.RFeyeList.Latitude(ii)  = gps.Latitude;
                        app.RFeyeList.Longitude(ii) = gps.Longitude;
                    end
                    
                    % EMISSIONS
                    d.Message = 'Em andamento tentativa de identificar as emissões...';
                    switch TagMode
                        case "EMISSÕES DESMASCARADAS"
                            tempSheet     = app.UnMaskedEmissions;
                            tempSheet_Web = app.UnMaskedWebSheet;
                            Peaks         = UnMasked_PeaksTable(app, Peaks);
                        case "EMISSÕES PERSISTENTES"
                            tempSheet     = app.PersistentEmissions;
                            tempSheet_Web = app.PersistentWebSheet;                            
                            Peaks         = udpMonitor_udpLoggerRead(app, Peaks, app.RFeyeList.Name{ii}, d);
                    end

                    % PEAKS    
                    if ~isempty(Peaks)
                        global AnatelDB
                        global ExceptionGlobalList
                        
                        if isempty(AnatelDB)
                            anateldb_Read(app.RootFolder)
                        end
                        
                        if isempty(ExceptionGlobalList)
                            Misc_ExceptionGlobalList(app.RootFolder)
                        end

                        for jj = 1:height(Peaks)
                            Peaks(jj,:) = Misc_closestStation(app, Peaks(jj,:), struct('Latitude', app.RFeyeList.Latitude(ii), 'Longitude', app.RFeyeList.Longitude(ii)));
                            
                            Tag = sprintf("%s_%.0f_%.0f", app.RFeyeList.Name{ii}, Peaks.Truncated(jj), Peaks.BW(jj));
                            if isempty(find(tempSheet.Tag == Tag, 1))
                                tempSheet(end+1, :) = {app.RFeyeList.State{ii},    ...
                                                       app.RFeyeList.Location{ii}, ...
                                                       app.RFeyeList.Name{ii},     ...
                                                       Peaks.Frequency(jj)/1000,   ...
                                                       Peaks.Truncated(jj)/1000,   ...
                                                       Peaks.BW(jj),               ...
                                                       Peaks.OCC(jj),              ...
                                                       Peaks.Description{jj},      ...
                                                       Peaks.Distance{jj}, beginTime, Tag};
    
                                if Peaks.BW(jj) > 0                        
                                    indFreq = find(tempSheet_Web.Node == app.RFeyeList.Name{ii} & ...
                                                  (abs(Peaks.Truncated(jj) - 1000*tempSheet_Web.Truncated) < 15), 1);
                                    
                                    if isempty(indFreq)
                                        position = height(tempSheet_Web) + 1;
                                        tempSheet_Web = Misc_AddInfoToSheet(app, TagMode, tempSheet_Web, app.RFeyeList.Name{ii}, Peaks(jj,:), position, beginTime);
                                    else
                                        tempSheet_Web = Misc_AddInfoToSheet(app, TagMode, tempSheet_Web, app.RFeyeList.Name{ii}, Peaks(jj,:), indFreq,  beginTime);
                                    end
                                end
                            end
                        end
                        
                        % if the emission is in UnMaskedWebSheet but not in the mask... update
                        % emission "InMask" field accordingly
                        if TagMode == "EMISSÕES DESMASCARADAS"
                            indNode = find(tempSheet_Web.Node == app.RFeyeList.Name{ii});
                            for kk = indNode'
                                indEmiss = find(abs(Peaks.Truncated - 1000*tempSheet_Web.Truncated(kk)) < 15, 1);

                                if isempty(indEmiss)                                    
                                    tempSheet_Web.Status(kk) = "Resolvido";
                                    tempSheet_Web.InMask(kk) = "Não";
                                end
                            end
                        end

                        Misc_LogWrite(app, app.RFeyeList.Name{ii}, sprintf('%s ([MHz, kHz]): %s',   TagMode, jsonencode([round(Peaks.Frequency/1000, 3), double(Peaks.BW)])))
                    else
                        Misc_LogWrite(app, app.RFeyeList.Name{ii}, sprintf('Não identificadas %s.', TagMode))
                    end
                    Misc_TreeNodeColor(app, app.RFeyeList.Name{ii}, true)

                catch ME
                    MessageBox(app, 'error', getReport(ME))

                    Misc_LogWrite(app, app.RFeyeList.Name{ii}, ME.message)
                    Misc_TreeNodeColor(app, app.RFeyeList.Name{ii}, false)
                end

                switch TagMode
                    case "EMISSÕES DESMASCARADAS"
                        app.UnMaskedEmissions   = tempSheet;
                        app.UnMaskedWebSheet    = tempSheet_Web;
                        log = jsonencode(rmfield(app.General, {'WebSheetPath', 'Persistent'}));
                    case "EMISSÕES PERSISTENTES"
                        app.PersistentEmissions = tempSheet;
                        app.PersistentWebSheet  = tempSheet_Web;
                        log = jsonencode(rmfield(app.General, {'WebSheetPath', 'Bands'}));
                end
                RadioGroupSelectionChanged(app)

                endTime = datestr(now, 'dd/mm/yyyy HH:MM:SS');
                Misc_integraUpdate(app, TagMode, {app.RFeyeList.Name{ii}, log, app.RFeyeList.Monitor{ii}, beginTime, endTime})

                if d.CancelRequested
                    break
                end
            end

            app.Tree.CheckedNodes = [];
                     
        end

        % Selection changed function: RadioGroup
        function RadioGroupSelectionChanged(app, event)

            if app.Option1.Value
                app.Subtitle.Text           = 'MODO EMISSÕES DESMASCARADAS';
                app.Qtd_EmissionsLabel.Text = {'EMISSÕES'; 'DESMASCARADAS'};
                
                app.Table.Data = app.UnMaskedEmissions(:,3:9);

            elseif app.Option2.Value
                app.Subtitle.Text           = 'MODO EMISSÕES PERSISTENTES ROMPENDO MÁSCARAS ESPECTRAIS';
                app.Qtd_EmissionsLabel.Text = {'EMISSÕES'; 'PERSISTENTES'};

                app.Table.Data = app.PersistentEmissions(:,3:9);
            end
            
            app.Qtd_Emissions.Text = num2str(height(app.Table.Data));
            app.Qtd_Unknown.Text   = num2str(sum(string(app.Table.Data.Description) == "-"));
            drawnow

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.mainGrid = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.mainGrid);
            app.Image.ImageSource = 'LT_Logger+.png';
            app.Title = uilabel(app.mainGrid);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="color: #262626; font-size: 24;">Monitor Logger +</b>'; '<font>Análise de emissões desmascaradas e persistentes</font>'; ' '};
            app.Panel_info1 = uipanel(app.mainGrid);
            app.Grid_info1 = uigridlayout(app.Panel_info1);
            app.Grid_info1.Tag = 'COLORLOCKED';
            app.Qtd_EmissionsLabel = uilabel(app.Grid_info1);
            app.Qtd_EmissionsLabel.Text = {'EMISSÕES'; 'DESMASCARADAS'};
            app.Qtd_Emissions = uilabel(app.Grid_info1);
            app.Qtd_Emissions.Text = '0';
            app.Panel_info2 = uipanel(app.mainGrid);
            app.Grid_info2 = uigridlayout(app.Panel_info2);
            app.Grid_info2.Tag = 'COLORLOCKED';
            app.Qtd_UnknownLabel = uilabel(app.Grid_info2);
            app.Qtd_UnknownLabel.Text = 'NÃO IDENTIFICADA';
            app.Qtd_Unknown = uilabel(app.Grid_info2);
            app.Qtd_Unknown.Text = '0';
            app.Subtitle = uilabel(app.mainGrid);
            app.Subtitle.Text = 'MODO EMISSÕES DESMASCARADAS';
            app.Panel_main = uipanel(app.mainGrid);
            app.Grid_main = uigridlayout(app.Panel_main);
            app.RadioGroupLabel = uilabel(app.Grid_main);
            app.RadioGroupLabel.Text = 'Tipo de monitoração:';
            app.RadioGroup = uibuttongroup(app.Grid_main);
            app.RadioGroup.SelectionChangedFcn = createCallbackFcn(app, @RadioGroupSelectionChanged, true);
            app.Option1 = uiradiobutton(app.RadioGroup);
            app.Option1.Text = 'Máscaras espectrais';
            app.Option1.Value = true;
            app.Option2 = uiradiobutton(app.RadioGroup);
            app.Option2.Text = 'Tráfego UDP';
            app.TreeLabel = uilabel(app.Grid_main);
            app.TreeLabel.Text = 'Sensores:';
            app.Tree = uitree(app.Grid_main, 'checkbox');
            app.Table_Grid = uigridlayout(app.Grid_main);
            app.Table_Grid.Tag = 'COLORLOCKED';
            app.Table = uitable(app.Table_Grid);
            app.Table.ColumnName = {''; ''; ''; ''; ''; ''; ''};
            app.Table.RowName = {};
            app.Table.ColumnSortable = [true true false true true true false];
            app.Table.SelectionType = 'row';
            app.Column1 = uilabel(app.Table_Grid);
            app.Column1.Text = {'  Sensor'; '  '};
            app.Column2 = uilabel(app.Table_Grid);
            app.Column2.Text = {'  Frequência'; '  (MHz)'};
            app.Column3 = uilabel(app.Table_Grid);
            app.Column3.Text = {'  Frequência'; '  truncada (MHz)'};
            app.Column4 = uilabel(app.Table_Grid);
            app.Column4.Text = {'  Largura de'; '  banda (kHz)'};
            app.Column5 = uilabel(app.Table_Grid);
            app.Column5.Text = {'  OCC'; '  (%)'};
            app.Column6 = uilabel(app.Table_Grid);
            app.Column6.Text = '  Identificação do provável emissor';
            app.Column7 = uilabel(app.Table_Grid);
            app.Column7.Text = {'  Distância'; '  (km)'};
            app.toolGrid = uigridlayout(app.mainGrid);
            app.Button_info = uibutton(app.toolGrid, 'push');
            app.Button_info.ButtonPushedFcn = createCallbackFcn(app, @Button_infoPushed, true);
            app.Button_info.Icon = 'LT_info.png';
            app.Button_info.Tooltip = {''};
            app.Button_info.Text = '';
            app.SettingsInfo = uilabel(app.toolGrid);
            app.SettingsInfo.Text = '';
            app.Button_play = uibutton(app.toolGrid, 'push');
            app.Button_play.ButtonPushedFcn = createCallbackFcn(app, @Button_playPushed, true);
            app.Button_play.Icon = 'DT_play.png';
            app.Button_play.Text = 'Iniciar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMonitor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end