classdef winRFeyeEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Grid1                    matlab.ui.container.GridLayout
        GridLayout5              matlab.ui.container.GridLayout
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        Panel_4                  matlab.ui.container.Panel
        GridLayout7              matlab.ui.container.GridLayout
        Grid4                    matlab.ui.container.GridLayout
        Panel_5                  matlab.ui.container.Panel
        GridLayout6              matlab.ui.container.GridLayout
        LOG                      matlab.ui.control.Label
        Sublabel2_3              matlab.ui.control.Label
        Panel2                   matlab.ui.container.Panel
        Grid3                    matlab.ui.container.GridLayout
        IP1                      matlab.ui.control.EditField
        IPPblicoIntranetLabel    matlab.ui.control.Label
        IP2                      matlab.ui.control.EditField
        IPPblicoIntranetLabel_2  matlab.ui.control.Label
        Sublabel2_2              matlab.ui.control.Label
        Sublabel1                matlab.ui.control.Label
        Panel4                   matlab.ui.container.Panel
        Grid5                    matlab.ui.container.GridLayout
        rootPass                 matlab.ui.control.HTML
        rootPassLabel            matlab.ui.control.Label
        ftpPort                  matlab.ui.control.Spinner
        PortaFTPSpinnerLabel     matlab.ui.control.Label
        sftpPort                 matlab.ui.control.Spinner
        PortaSFTPSpinnerLabel    matlab.ui.control.Label
        rootLogin                matlab.ui.control.EditField
        UsurioEditFieldLabel     matlab.ui.control.Label
        Panel3                   matlab.ui.container.Panel
        Grid4_3                  matlab.ui.container.GridLayout
        webPass                  matlab.ui.control.HTML
        webPassLabel             matlab.ui.control.Label
        webLogin                 matlab.ui.control.EditField
        UsurioEditFieldLabel_2   matlab.ui.control.Label
        Sublabel3                matlab.ui.control.Label
        Sublabel1_2              matlab.ui.control.Label
        Label1                   matlab.ui.control.Label
        Grid2                    matlab.ui.container.GridLayout
        Column7_2                matlab.ui.control.Label
        Column7                  matlab.ui.control.Label
        Column9_2                matlab.ui.control.Label
        Column2_2                matlab.ui.control.Label
        Column2                  matlab.ui.control.Label
        Column9                  matlab.ui.control.Label
        Column_1                 matlab.ui.control.Label
        Column0                  matlab.ui.control.Label
        Column4                  matlab.ui.control.Label
        Column3                  matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
    end
    
    methods (Access = private)
        
        function Fcn1_SelectedRowValues(app)

            row = app.UITable.Selection;

            app.IP1.Value       = app.CallingApp.RFeyeList.IP1{row};
            app.IP2.Value       = app.CallingApp.RFeyeList.IP2{row};

            app.webPass.Data    = app.CallingApp.RFeyeList.webPass{row};
            app.rootPass.Data   = app.CallingApp.RFeyeList.rootPass{row};

            app.ftpPort.Value   = app.CallingApp.RFeyeList.ftpPort(row);
            app.sftpPort.Value  = app.CallingApp.RFeyeList.sftpPort(row);

            app.LOG.Text        = app.CallingApp.RFeyeList.LOG{row};       
            
        end


        function Fcn2_Log(app, idx)

            if isempty(idx)
                logmsg = 'Não identificada alteração no registro.';

            else
                peakValues      = app.CallingApp.peaksTable(app.row,:);
                exceptionValues = app.CallingApp.exceptionList(idx,:);

                logmsg = {sprintf('<b style="color:#0073bd;">Emissão:</b> "%s" → "%s"',                   peakValues.Regulatory{1},  exceptionValues.Regulatory{1}); ...
                          sprintf('<b style="color:#0073bd;">Serviço:</b> "%.0f" → "%.0f"',               peakValues.Service,        exceptionValues.Service);       ...
                          sprintf('<b style="color:#0073bd;">Estação:</b> "%.0f" → "%.0f"',               peakValues.Station,        exceptionValues.Station);       ...
                          sprintf('<b style="color:#0073bd;">Tipo de emissão:</b> "%s" → "%s"',           peakValues.Type{1},        exceptionValues.Type{1});       ...
                          sprintf('<b style="color:#0073bd;">Indício de irregularidade:</b> "%s" → "%s"', peakValues.Irregular{1},   exceptionValues.Irregular{1});  ...
                          sprintf('<b style="color:#0073bd;">Potencial lesivo:</b> "%s" → "%s"',          peakValues.RiskLevel{1},   exceptionValues.RiskLevel{1});  ...
                          sprintf('<b style="color:#0073bd;">Descrição:</b> "%s" → "%s"',                 peakValues.Description{1}, exceptionValues.Description{1})};

                if strcmp(peakValues.Description{1}, exceptionValues.Description{1}); logmsg(7) = []; end
                if strcmp(peakValues.RiskLevel{1},   exceptionValues.RiskLevel{1});   logmsg(6) = []; end
                if strcmp(peakValues.Irregular{1},   exceptionValues.Irregular{1});   logmsg(5) = []; end
                if strcmp('Fundamental',             exceptionValues.Type{1});        logmsg(4) = []; end
                if        peakValues.Station   ==    exceptionValues.Station;         logmsg(3) = []; end
                if        peakValues.Service   ==    exceptionValues.Service;         logmsg(2) = []; end
                if strcmp(peakValues.Regulatory{1},  exceptionValues.Regulatory{1});  logmsg(1) = []; end

                logmsg = strjoin(logmsg, '; ');
            end

            app.LOG.Text = logmsg;

        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'toolbar_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.webPass.HTMLSource  = '<!DOCTYPE html><html><head><script type="text/javascript">function setup(htmlComponent) {var dom = document.getElementById("pwrd");window.updateData = function (newData) {htmlComponent.Data = dom.value;};htmlComponent.addEventListener("DataChanged", function() {dom.value = htmlComponent.Data;});}</script></head><body><input type="password" id="pwrd" onchange="updateData()" style="padding-left: 3px; width: 106px; height: 16px"></input></body></html>';
            app.rootPass.HTMLSource = app.webPass.HTMLSource;

            set(app.UITable, 'Data', app.CallingApp.RFeyeList(:, [1:3, 6:11, 13]), ...
                             'Selection', 1)

            Fcn1_SelectedRowValues(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin1 = [];
            delete(app)
            
        end

        % Cell selection callback: UITable
        function UITable_CellSelection(app, event)
            
            Fcn1_SelectedRowValues(app)
            
        end

        % Callback function
        function Button1Pushed(app, event)
            
            global ExceptionGlobalList
            
            if isempty(ExceptionGlobalList)
                Misc_ExceptionGlobalList(app.RootFolder)
            end
            
            if isempty(ExceptionGlobalList)
                msg = 'Não identificada emissão na lista global de exceções contida no arquivo "ExceptionGlobalList.cfg".';

            else
                msg = [];
                for ii = 1:height(ExceptionGlobalList)
                    msg = sprintf('%s\n%s', msg, sprintf('<b>%.3f MHz</b> - "%s"', ExceptionGlobalList.Frequency(ii), ExceptionGlobalList.Description{ii}));
                end
            end

            Misc_uialertCreation(app, 'info', msg)

        end

        % Callback function
        function Button2Pushed(app, event)
            
            idx = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  app.CallingApp.peaksTable.Tag(app.row))), ...
                            find(app.CallingApp.exceptionList.Frequency == app.CallingApp.peaksTable.Frequency(app.row)));

            app.CallingApp.exceptionList(idx,:) = [];
            app.row = 1;

            Fcn1_TableStyle(app)
            app.UITable.Selection = [1, 1];
            Fcn1_SelectedRowValues(app)

            scroll(app.UITable, 'top')

        end

        % Callback function
        function Button3_Clicked(app, event)
            
            idx = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  app.CallingApp.peaksTable.Tag(app.row))), ...
                            find(app.CallingApp.exceptionList.Frequency == app.CallingApp.peaksTable.Frequency(app.row)));

            if ~isempty(idx)
                idx = idx(1);
            end

            if ~strcmp(app.CallingApp.peaksTable.Regulatory{app.row}, app.RegInfo_edited.Value) || ...
               app.CallingApp.peaksTable.Station(app.row) ~= app.Station_edited.Value               || ...
               ~strcmp(app.CallingApp.peaksTable.Type{app.row}, app.Type.Value)                     || ...
               ~strcmp(app.CallingApp.peaksTable.Irregular{app.row}, app.Compliance.Value)         || ...
               ~strcmp(app.CallingApp.peaksTable.RiskLevel{app.row}, app.RiskLevel.Value)           || ...
               ~isempty(deblank(strjoin(app.Info.Value)))

                stationInfo = '';
                try
                    stationInfo = jsondecode(app.Info.Value{1});
                catch
                end
    
                if isstruct(stationInfo)
                    exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},       ...
                                      app.CallingApp.peaksTable.Frequency(app.row), ...
                                      app.Type.Value,                               ...
                                      app.RegInfo_edited.Value,                     ...
                                      str2double(stationInfo.Service),              ...
                                      str2double(stationInfo.Station),              ...
                                      stationInfo.Description,                      ...
                                      stationInfo.Distance,                         ...
                                      app.Compliance.Value,                         ...
                                      app.RiskLevel.Value};
    
                else
                    switch app.RegInfo_edited.Value
                        case 'Licenciada'
                            if isempty(idx)
                                exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},          ...
                                                  app.CallingApp.peaksTable.Frequency(app.row),    ...
                                                  app.Type.Value,                                  ...
                                                  app.RegInfo_edited.Value,                        ...
                                                  app.CallingApp.peaksTable.Service(app.row),      ...
                                                  app.CallingApp.peaksTable.Station(app.row),      ...
                                                  app.CallingApp.peaksTable.Description{app.row},  ...
                                                  app.CallingApp.peaksTable.Distance{app.row},     ...
                                                  app.Compliance.Value,                            ...
                                                  app.RiskLevel.Value};
                            else
                                if app.Station_edited.Value == app.CallingApp.peaksTable.Station(idx)
                                    Distance = app.CallingApp.peaksTable.Distance{app.row};
                                else
                                    Distance = app.CallingApp.exceptionList.Distance{idx};
                                end

                                exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},          ...
                                                  app.CallingApp.peaksTable.Frequency(app.row),    ...
                                                  app.Type.Value,                                  ...
                                                  app.RegInfo_edited.Value,                        ...
                                                  app.Service_edited.Value,                        ...
                                                  app.Station_edited.Value,                        ...
                                                  app.Info.Value,                                  ...
                                                  Distance,                                        ...
                                                  app.Compliance.Value,                            ...
                                                  app.RiskLevel.Value};
                            end

                        otherwise
                            if isempty(deblank(strjoin(app.Info.Value)))
                                Misc_uialertCreation(app, 'warning', 'Campo "Informações adicionais" não pode ficar vazio.')
                                return
                            end

                            exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},          ...
                                              app.CallingApp.peaksTable.Frequency(app.row),    ...
                                              app.Type.Value,                                  ...
                                              app.RegInfo_edited.Value,                        ...
                                              -1, -1, ['[EXC] ' strjoin(app.Info.Value)], '-', ...
                                              app.Compliance.Value,                            ...
                                              app.RiskLevel.Value};
                    end
                end

                if isempty(idx); app.CallingApp.exceptionList(end+1,:) = exceptionValue;
                else;            app.CallingApp.exceptionList(idx,:)   = exceptionValue;
                end

                Fcn1_TableStyle(app)            
                Fcn1_SelectedRowValues(app)

            else
                Button2Pushed(app)
            end
            
            app.CallingApp.auxWin_SignalAnalysis
            
        end

        % Callback function
        function Station_editedValueChanged(app, event)
            
            latNode  = app.CallingApp.peaksTable.Latitude(app.row);
            longNode = app.CallingApp.peaksTable.Longitude(app.row);
            
            try
                StationInfo = anateldb_Query(app.Station_edited.Value, latNode, longNode, app.RootFolder);
                
                msg = sprintf(['<font style="font-size:12;">Indicação automática:\n• Frequência: %.3f MHz\n• Descrição: %s\n• Distância: %s\n\n'                      ...
                               'Indicação manual:    \n• Frequência(s): %s MHz\n• Descrição: %s\n• Distância: %.1f\n\n Confirma edição?</font>'], ...
                               app.CallingApp.peaksTable.Frequency(app.row), app.CallingApp.peaksTable.Description{app.row},              ...
                               app.CallingApp.peaksTable.Distance{app.row}, StationInfo.Frequency, StationInfo.Description, StationInfo.Distance);
    
                selection = uiconfirm(app.UIFigure, msg, 'appAnálise', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Interpreter', 'html');
                
                if strcmp(selection, 'OK')
                    set(app.Info, 'Value', jsonencode(struct('Service', num2str(StationInfo.Service), ...
                                                             'Station', num2str(StationInfo.Station), ...
                                                             'Description', StationInfo.Description,  ...
                                                             'Distance', sprintf('%.1f', StationInfo.Distance))), ...
                                  'Editable', 0, 'BackgroundColor', [.80,.80,.80])

                else
                    app.Station_edited.Value = double(app.CallingApp.peaksTable.Station(app.row));
                end

            catch ME
                app.Station_edited.Value = double(app.CallingApp.peaksTable.Station(app.row));
                
                Misc_uialertCreation(app, 'error', getReport(ME))
            end
            
        end

        % Callback function
        function RegInfo_editedValueChanged(app, event)
            
            switch app.RegInfo_edited.Value
                case 'Licenciada'
                    set(app.Station_edited, 'Editable', 1, 'BackgroundColor', [1,1,1])
                    set(app.Info,    'Editable', 0, 'BackgroundColor', [.80,.80,.80], 'Value', '')
                    
                    set(app.Type, 'Value', 'Fundamental', 'Enable', 0)
                    app.TypeLabel.Enable = 0;
    
                    set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')

                case {'Não licenciada', 'Não passível de licenciamento'}
                    set(app.Station_edited, 'Editable', 0, 'BackgroundColor', [.94,.94,.94], 'Value', -1)
                    set(app.Info,    'Editable', 1, 'BackgroundColor', [1,1,1])
    
                    app.Type.Enable      = 1;
                    app.TypeLabel.Enable = 1;
    
                    switch app.RegInfo_edited.Value
                        case 'Não licenciada'
                            set(app.Compliance, 'Items', {'Sim'})
                        case 'Não passível de licenciamento'
                            set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')
                    end
            end
            ComplianceValueChanged(app)
            
        end

        % Callback function
        function xLimFactor_DownClicked(app, event)
            
            if app.xLimFactor > .5
                app.xLimFactor = app.xLimFactor - .5;
                app.xLimFactorValue.Text = num2str(2*app.xLimFactor);
    
                [F0_axes, F1_axes] = Fcn4_xLim(app);
                set(app.UIAxes, 'XLim', [F0_axes, F1_axes])
            end

        end

        % Callback function
        function xLimFactor_UpClicked(app, event)
            
            if app.xLimFactor < 10
                app.xLimFactor = app.xLimFactor + .5;
                app.xLimFactorValue.Text = num2str(2*app.xLimFactor);
    
                [F0_axes, F1_axes] = Fcn4_xLim(app);
                set(app.UIAxes, 'XLim', [F0_axes, F1_axes])
            end

        end

        % Callback function
        function ComplianceValueChanged(app, event)
            
            if strcmp(app.Compliance.Value, 'Não')
                set(app.RiskLevel,      'Enable', 0, 'Items', {'-'})
                set(app.RiskLevelLabel, 'Enable', 0)
            else
                set(app.RiskLevel,      'Enable', 1, 'Items', {'Baixo', 'Médio', 'Alto'})
                set(app.RiskLevelLabel, 'Enable', 1)
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = char(extractBetween(info, sprintf('<winSignalAnalysis>\n'), sprintf('\n</winSignalAnalysis>')));

            Misc_uialertCreation(app, 'info', msg)

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            result = Fcn_IP(app);
            if result
                d = uiprogressdlg(app.UIFigure, "Message", "Em andamento...", "Indeterminate", "on");
    
                app.Status = Misc_RFeyeDatabase(app.RootFolder,                         ...
                                                app.IP1.Value, app.IP2.Value,           ...
                                                app.webLogin.Value, app.webPass.Data,   ...
                                                app.rootLogin.Value, app.rootPass.Data, ...
                                                app.ftpPort.Value, app.sftpPort.Value);
    
                if app.Status.ConnectFlag
                    app.Lamp.Color = [0,1,0];
                    app.Button_2.Enable = 1;

                    if isempty(app.Status.ID)
                        msg  = 'Não identificado o sensor sob análise.';
                        icon = 'warning';
                    else
                        if strcmp(app.Status.ID, app.Node.Value)
                            msg  = sprintf('Confirmado se tratar do sensor %s.', app.Status.ID); 
                            icon = 'success';
                        else
                            msg  = sprintf('Confirmado se tratar do sensor %s, diferente do selecionado (%s).', app.Status.ID, app.Node.Value);
                            icon = 'error'; 
                            app.Button_2.Enable = 0;
                        end
                    end
                    msg = sprintf('%s\n\n%s', msg, jsonencode(rmfield(app.Status, {'IP1', 'IP2', 'webPass', 'rootPass', 'ftpPort', 'sftpPort', 'ID', 'ConnectFlag', 'LogDate'})));
                else
                    msg = 'Sensor não respondeu às tentativas de conexão.';
                    icon = 'error'; 
                    
                    app.Lamp.Color = [1,0,0];
                    app.Button_2.Enable = 0;
                end
                uialert(app.UIFigure, msg, 'appColeta', 'Icon', icon)

            end
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            
            ind = find(strcmp(app.CallingApp.RFeyeList.ID, app.Node.Value), 1);

            try
                app.CallingApp.RFeyeList(ind, 2:21) = {app.Status.IP1, ...
                                                       app.Status.IP2, ...
                                                       app.Status.webPass, ...
                                                       app.Status.rootPass, ...
                                                       app.Status.ftpPort, ...
                                                       app.Status.sftpPort, ...
                                                       app.Status.sftpHostname, ...
                                                       app.Status.LoggerNodeInfo, ...
                                                       app.Status.Latitude, ...
                                                       app.Status.Longitude, ...
                                                       app.Status.City, ...
                                                       app.Status.State, ...
                                                       app.Status.webNodeStatus, ...
                                                       app.Status.webCoordinatesStatus, ...
                                                       app.Status.ftpStatus, ...
                                                       app.Status.sftpStatus, ...
                                                       app.Status.ftpStatus_OpenVPN, ...
                                                       app.Status.sftpStatus_OpenVPN, ...
                                                       app.Status.LogDate, ...
                                                       app.Status.ConnectFlag};
    
                app.CallingApp.logger_NodeCreation
                closeFcn(app)
                
            catch ME
                uialert(app.UIFigure, ME.message, 'appColeta', 'Icon', 'error')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta: Receptores RFeye Node 20-6';
            app.UIFigure.Icon = 'home_Puzzle.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Grid2 = uigridlayout(app.Grid1);
            app.UITable = uitable(app.Grid2);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.UITable.Multiselect = 'off';
            app.Column3 = uilabel(app.Grid2);
            app.Column3.Text = {'  sftpPort'; ' '; ''};
            app.Column4 = uilabel(app.Grid2);
            app.Column4.Text = {'  Latitude'; ' '};
            app.Column0 = uilabel(app.Grid2);
            app.Column0.Text = {'  IP1'; '  '};
            app.Column_1 = uilabel(app.Grid2);
            app.Column_1.Text = {'  ID'; '  '};
            app.Column9 = uilabel(app.Grid2);
            app.Column9.Text = {'  UF'; ' '};
            app.Column2 = uilabel(app.Grid2);
            app.Column2.Text = {'  IP2'; '  '};
            app.Column2_2 = uilabel(app.Grid2);
            app.Column2_2.Text = {'  ftpPort'; ' '};
            app.Column9_2 = uilabel(app.Grid2);
            app.Column9_2.Text = {'  Conectividade'; ' '};
            app.Column7 = uilabel(app.Grid2);
            app.Column7.Text = {'  Longitude'; '  '};
            app.Column7_2 = uilabel(app.Grid2);
            app.Column7_2.Text = {'  Localidade'; '  '};
            app.Panel_4 = uipanel(app.Grid1);
            app.GridLayout7 = uigridlayout(app.Panel_4);
            app.Grid4 = uigridlayout(app.GridLayout7);
            app.Label1 = uilabel(app.Grid4);
            app.Label1.Text = 'ASPECTOS GERAIS';
            app.Sublabel1_2 = uilabel(app.Grid4);
            app.Sublabel1_2.Text = 'Endereço(s) IP';
            app.Sublabel3 = uilabel(app.Grid4);
            app.Sublabel3.Text = 'FTP/SFTP';
            app.Panel3 = uipanel(app.Grid4);
            app.Grid4_3 = uigridlayout(app.Panel3);
            app.UsurioEditFieldLabel_2 = uilabel(app.Grid4_3);
            app.UsurioEditFieldLabel_2.Text = 'Usuário:';
            app.webLogin = uieditfield(app.Grid4_3, 'text');
            app.webLogin.Value = 'admin';
            app.webPassLabel = uilabel(app.Grid4_3);
            app.webPassLabel.Text = 'Senha:';
            app.webPass = uihtml(app.Grid4_3);
            app.Panel4 = uipanel(app.Grid4);
            app.Grid5 = uigridlayout(app.Panel4);
            app.UsurioEditFieldLabel = uilabel(app.Grid5);
            app.UsurioEditFieldLabel.Text = 'Usuário:';
            app.rootLogin = uieditfield(app.Grid5, 'text');
            app.rootLogin.Value = 'root';
            app.PortaSFTPSpinnerLabel = uilabel(app.Grid5);
            app.PortaSFTPSpinnerLabel.Text = 'Porta SFTP:';
            app.sftpPort = uispinner(app.Grid5);
            app.sftpPort.Limits = [0 65535];
            app.sftpPort.RoundFractionalValues = 'on';
            app.sftpPort.ValueDisplayFormat = '%.0f';
            app.sftpPort.Value = 2828;
            app.PortaFTPSpinnerLabel = uilabel(app.Grid5);
            app.PortaFTPSpinnerLabel.Text = 'Porta FTP:';
            app.ftpPort = uispinner(app.Grid5);
            app.ftpPort.Limits = [0 65535];
            app.ftpPort.RoundFractionalValues = 'on';
            app.ftpPort.ValueDisplayFormat = '%.0f';
            app.ftpPort.Value = 21;
            app.rootPassLabel = uilabel(app.Grid5);
            app.rootPassLabel.Text = 'Senha:';
            app.rootPass = uihtml(app.Grid5);
            app.Sublabel1 = uilabel(app.Grid4);
            app.Sublabel1.Text = 'O teste de conectividade consiste na tentativa de criação de objetos FTP e SFTP e na tentativa de extração de informação do ambiente web do sensor.';
            app.Sublabel2_2 = uilabel(app.Grid4);
            app.Sublabel2_2.Text = 'Web';
            app.Panel2 = uipanel(app.Grid4);
            app.Grid3 = uigridlayout(app.Panel2);
            app.IPPblicoIntranetLabel_2 = uilabel(app.Grid3);
            app.IPPblicoIntranetLabel_2.Text = 'OpenVPN:';
            app.IP2 = uieditfield(app.Grid3, 'text');
            app.IPPblicoIntranetLabel = uilabel(app.Grid3);
            app.IPPblicoIntranetLabel.Text = 'Público / Intranet:';
            app.IP1 = uieditfield(app.Grid3, 'text');
            app.Sublabel2_3 = uilabel(app.Grid4);
            app.Sublabel2_3.Text = 'LOG';
            app.Panel_5 = uipanel(app.Grid4);
            app.GridLayout6 = uigridlayout(app.Panel_5);
            app.LOG = uilabel(app.GridLayout6);
            app.LOG.Interpreter = 'html';
            app.LOG.Text = '';
            app.GridLayout5 = uigridlayout(app.Grid1);
            app.Button = uibutton(app.GridLayout5, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'toolbar_info.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout5, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'ConnectWhite.png';
            app.Button_2.Text = 'Conectividade';
            app.Button_3 = uibutton(app.GridLayout5, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'General_editWhite.png';
            app.Button_3.Enable = 'off';
            app.Button_3.Text = 'Editar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winRFeyeEdit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end