classdef winSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        realtime_wFall_Depth       matlab.ui.control.DropDown
        realtime_wFall_DepthLabel  matlab.ui.control.Label
        realtime_wFall_Style       matlab.ui.control.DropDown
        realtime_wFall_StyleLabel  matlab.ui.control.Label
        Grid1                      matlab.ui.container.GridLayout
        VariablesLabel             matlab.ui.control.Label
        InfoLabel                  matlab.ui.control.Label
        Button2                    matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        Grid2                      matlab.ui.container.GridLayout
        CheckBox8                  matlab.ui.control.CheckBox
        Variables_Label1           matlab.ui.control.Label
        Variables_Label3           matlab.ui.control.Label
        CheckBox7                  matlab.ui.control.CheckBox
        CheckBox6                  matlab.ui.control.CheckBox
        CheckBox5                  matlab.ui.control.CheckBox
        CheckBox4                  matlab.ui.control.CheckBox
        CheckBox3                  matlab.ui.control.CheckBox
        CheckBox2                  matlab.ui.control.CheckBox
        CheckBox1                  matlab.ui.control.CheckBox
        Button1                    matlab.ui.control.Button
        Info                       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        
        CallingApp
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, RootFolder)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            
            [MatlabVersion, ReleaseDate] = version;
            MatlabRoot                   = matlabroot;
            InstalledProducts            = ver;
            MatlabProducts = {};
            for ii = 1:numel(InstalledProducts)
                MatlabProducts = [MatlabProducts; sprintf('%s v. %s', InstalledProducts(ii).Name, InstalledProducts(ii).Version)];
            end
            
            Python = pyenv;
            
            % inserir relação de toolboxes que precisam.
            % Verificar versão da biblioteca do Fiscaliza.
            
            app.Info.Value = sprintf(['appColeta\n• v. 0.53\n• %s\n\n'                     ...
                                      'Matlab\n• v. %s (Release date: %s)\n• %s\n• %s\n\n' ...
                                      'Python\n• v. %s\n• %s\n• %s\n• %s.'],               ...
                                      RootFolder,     ...
                                      MatlabVersion,  ...
                                      ReleaseDate,    ...
                                      strjoin(MatlabProducts, ', '), ...
                                      MatlabRoot,     ...
                                      Python.Version, ...
                                      Python.Home,    ...
                                      Python.Library, ...
                                      Python.Executable);
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin5 = [];
            delete(app)
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
            if app.CheckBox1.Value
                assignin('base', 'instrList', app.CallingApp.instrList)
                openvar('instrList')
            end
            
            if app.CheckBox2.Value
                assignin('base', 'taskList', app.CallingApp.taskList)
                openvar('taskList')
            end
            
            if app.CheckBox3.Value
                assignin('base', 'bandList', app.CallingApp.bandList)
                openvar('bandList')
            end
            
            if app.CheckBox4.Value
                assignin('base', 'instrInfo', app.CallingApp.instrInfo)
                openvar('instrInfo')
            end
            
            if app.CheckBox5.Value
                assignin('base', 'timeInfo', app.CallingApp.timeInfo)
                openvar('timeInfo')
            end
            
            if app.CheckBox6.Value
                assignin('base', 'specInfo', app.CallingApp.specInfo)
                openvar('specInfo')
            end
            
            if app.CheckBox7.Value
                assignin('base', 'gpsInfo', app.CallingApp.gpsInfo)
                openvar('gpsInfo')
            end
            
            if app.CheckBox8.Value
                assignin('base', 'specMatrix', app.CallingApp.specMatrix)
                openvar('specMatrix')
            end
            
        end

        % Value changed function: CheckBox1, CheckBox2, CheckBox3, 
        % CheckBox4, CheckBox5, CheckBox6, CheckBox7, CheckBox8
        function CheckBox1ValueChanged(app, event)
            
            if app.CheckBox1.Value || app.CheckBox2.Value || app.CheckBox3.Value || app.CheckBox4.Value || ...
               app.CheckBox5.Value || app.CheckBox6.Value || app.CheckBox7.Value || app.CheckBox8.Value
                app.Button2.Enable = 1;
            else
                app.Button2.Enable = 0;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'Bluemine.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Info = uitextarea(app.Grid1);
            app.Info.Value = {'General info...'};
            app.Button1 = uibutton(app.Grid1, 'push');
            app.Button1.Icon = 'Refresh.jpg';
            app.Button1.Enable = 'off';
            app.Button1.Text = 'Atualizar';
            app.Panel = uipanel(app.Grid1);
            app.Grid2 = uigridlayout(app.Panel);
            app.CheckBox1 = uicheckbox(app.Grid2);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox1.Text = 'instrList';
            app.CheckBox2 = uicheckbox(app.Grid2);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox2.Text = 'taskList';
            app.CheckBox3 = uicheckbox(app.Grid2);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox3.Text = 'bandList';
            app.CheckBox4 = uicheckbox(app.Grid2);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox4.Text = 'instrInfo';
            app.CheckBox5 = uicheckbox(app.Grid2);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox5.Text = 'timeInfo';
            app.CheckBox6 = uicheckbox(app.Grid2);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox6.Text = 'specInfo';
            app.CheckBox7 = uicheckbox(app.Grid2);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox7.Text = 'gpsInfo';
            app.Variables_Label3 = uilabel(app.Grid2);
            app.Variables_Label3.Text = 'Relacionadas à YYY...';
            app.Variables_Label1 = uilabel(app.Grid2);
            app.Variables_Label1.Text = 'Relacionadas à XXX...';
            app.CheckBox8 = uicheckbox(app.Grid2);
            app.CheckBox8.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox8.Text = 'specMatrix';
            app.CheckBox8.Value = true;
            app.Button2 = uibutton(app.Grid1, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Icon = 'Media_PlayWhite.png';
            app.Button2.Text = 'Explorar dados';
            app.InfoLabel = uilabel(app.Grid1);
            app.InfoLabel.Text = 'Informações gerais';
            app.VariablesLabel = uilabel(app.Grid1);
            app.VariablesLabel.Text = 'Visualização das principais variáveis do appColeta';
            app.realtime_wFall_StyleLabel = uilabel(app.UIFigure);
            app.realtime_wFall_StyleLabel.Enable = 'off';
            app.realtime_wFall_StyleLabel.Text = 'Estilo:';
            app.realtime_wFall_Style = uidropdown(app.UIFigure);
            app.realtime_wFall_Style.Items = {'hot', 'summer', 'bone', 'copper', 'jet'};
            app.realtime_wFall_Style.Enable = 'off';
            app.realtime_wFall_Style.Value = 'jet';
            app.realtime_wFall_DepthLabel = uilabel(app.UIFigure);
            app.realtime_wFall_DepthLabel.Enable = 'off';
            app.realtime_wFall_DepthLabel.Text = 'Profundidade:';
            app.realtime_wFall_Depth = uidropdown(app.UIFigure);
            app.realtime_wFall_Depth.Items = {'64 amostras', '128 amostras', '256 amostras', '512 amostras', '1024 amostras'};
            app.realtime_wFall_Depth.Enable = 'off';
            app.realtime_wFall_Depth.Value = '256 amostras';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end